classdef FFCProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        QualityAssurancePanel          matlab.ui.container.Panel
        AllButton                      matlab.ui.control.Button
        THKButton                      matlab.ui.control.Button
        SNRButton                      matlab.ui.control.Button
        UniformityButton               matlab.ui.control.Button
        GeometricAccButton             matlab.ui.control.Button
        MultichannelDropDown           matlab.ui.control.ListBox
        ExportOptionsPanel             matlab.ui.container.Panel
        CreateFigurePanel              matlab.ui.container.Panel
        ImageButton                    matlab.ui.control.Button
        kspaceButton                   matlab.ui.control.Button
        ExportscanparameterstoworkspaceButton  matlab.ui.control.Button
        ExportstackedimagesButton      matlab.ui.control.Button
        ExportallimagesButton          matlab.ui.control.Button
        ExportscantoworkspaceButton    matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        BackgroundselectionButton      matlab.ui.control.StateButton
        ReprocessingButton             matlab.ui.control.StateButton
        LoadDataSetButton              matlab.ui.control.Button
        RelaxometryPanel               matlab.ui.container.Panel
        ColourAxisLowerLimitEditField  matlab.ui.control.NumericEditField
        ColourAxisLowerLimitEditFieldLabel  matlab.ui.control.Label
        ColourAxisUpperLimitEditField  matlab.ui.control.NumericEditField
        ColourAxisUpperLimitEditFieldLabel  matlab.ui.control.Label
        DisplayingasT1Button           matlab.ui.control.StateButton
        LogarithmicMapsButton          matlab.ui.control.StateButton
        CheckfitsButton                matlab.ui.control.StateButton
        GenerateROIDispersionCurveButton  matlab.ui.control.Button
        GenerateRelaxationMapsButton   matlab.ui.control.Button
        ScanListPanel                  matlab.ui.container.Panel
        ScanList                       matlab.ui.control.ListBox
        DisplayOptionsPanel            matlab.ui.container.Panel
        IndividualNormalisationButton  matlab.ui.control.StateButton
        FlipVerticallyButton           matlab.ui.control.Button
        FlipHorizontallyButton         matlab.ui.control.Button
        RotateImageButton              matlab.ui.control.Button
        WindowWidthSlider              matlab.ui.control.Slider
        WindowWidthSliderLabel         matlab.ui.control.Label
        WindowLevelSlider              matlab.ui.control.Slider
        WindowLevelSliderLabel         matlab.ui.control.Label
        CurrentImagePanel              matlab.ui.container.Panel
        RegisterImagesButton           matlab.ui.control.Button
        ImageTypeDropDown              matlab.ui.control.DropDown
        ImageTypeDropDownLabel         matlab.ui.control.Label
        SliceDropDown                  matlab.ui.control.DropDown
        SliceDropDownLabel             matlab.ui.control.Label
        EvolutionTimeDropDown          matlab.ui.control.DropDown
        EvolutionTimeDropDownLabel     matlab.ui.control.Label
        EvolutionFieldDropDown         matlab.ui.control.DropDown
        EvolutionFieldDropDownLabel    matlab.ui.control.Label
        panel_recon                    matlab.ui.container.Panel
        FFTDropDown_3D                 matlab.ui.control.DropDown
        Sizeof3DFFTLabel               matlab.ui.control.Label
        FilterSettingsButton           matlab.ui.control.Button
        BiasFieldDropDown              matlab.ui.control.DropDown
        BiasFieldDropDownLabel         matlab.ui.control.Label
        CombinationDropDown            matlab.ui.control.DropDown
        CombinationDropDownLabel       matlab.ui.control.Label
        RebuildImagesButton            matlab.ui.control.Button
        WindowSizeEditField            matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        WindowDropDown                 matlab.ui.control.DropDown
        WindowDropDownLabel            matlab.ui.control.Label
        FFTDropDown                    matlab.ui.control.DropDown
        Sizeof2DFFTLabel               matlab.ui.control.Label
        axis_coordinates               matlab.ui.control.UIAxes
        axis_image                     matlab.ui.control.UIAxes
        axis_kspace                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentscan % Currently selected scan
        scan_data %actual processed scan data
        currentmap
        mask

        filter_preview_original = []
        filter_preview_scan = []
        filter_preview_active = false
    end
    
    methods (Access = private)
        
        function   activeimage(app)
            app.currentscan = app.ScanList.Value;
            if isempty(app.scan_data{app.currentscan}.rot_theta)
                app.scan_data{app.currentscan}.rot_theta = 0;
            end

            if ~isprop(app.scan_data{app.currentscan}, 'geomT') || ...
                    isempty(app.scan_data{app.currentscan}.geomT)
              app.scan_data{app.currentscan}.geomT = eye(3);
            end

            %generate string lists with appropriate evolution fields, times etc
            fft_list = cellfun(@num2str,{app.scan_data{app.currentscan}.samples,app.scan_data{app.currentscan}.samples*2,app.scan_data{app.currentscan}.samples*4},'UniformOutput',false);
            bevol_list = sprintfc('%g',round(app.scan_data{app.currentscan}.fieldpoints,2));
            tevol_list = sprintfc('%g',round(app.scan_data{app.currentscan}.timepoints(1,:),2));
            
            
            %populate the GUI with the lists
            app.FFTDropDown.Items = fft_list;
            app.EvolutionFieldDropDown.Items = bevol_list;
            app.EvolutionTimeDropDown.Items = tevol_list;
            app.SliceDropDown.Items = sprintfc('%i',1:app.scan_data{app.currentscan}.slices);
            
            %allows use of indexing
            app.EvolutionFieldDropDown.ItemsData = 1:numel(app.EvolutionFieldDropDown.Items); %allows us to get indexes
            app.EvolutionTimeDropDown.ItemsData = 1:numel(app.EvolutionTimeDropDown.Items);
            app.SliceDropDown.ItemsData = 1:app.scan_data{app.currentscan}.slices;
            % Scanner receiver IDs are zero-based, whereas MATLAB array
            % indices are one-based.  Display 0..N-1 but retain 1..N as
            % ItemsData so callbacks can index the receiver dimension safely.
            app.MultichannelDropDown.Items = string(0:(app.scan_data{app.currentscan}.n_receivers-1));
            app.MultichannelDropDown.ItemsData = 1:numel(app.MultichannelDropDown.Items);
            
            if length(app.SliceDropDown.ItemsData) ==1
                app.SliceDropDown.Enable = 0;
            else
                app.SliceDropDown.Enable = 1;
            end
            
            if length(app.EvolutionTimeDropDown.ItemsData) ==1
                app.EvolutionTimeDropDown.Enable = 0;
                app.GenerateROIDispersionCurveButton.Enable=0;
                app.GenerateRelaxationMapsButton.Enable =0;
                
                
            else
                app.EvolutionTimeDropDown.Enable = 1;
                app.GenerateROIDispersionCurveButton.Enable=1;
                app.GenerateRelaxationMapsButton.Enable =1;
                
            end
            
            if length(app.EvolutionFieldDropDown.ItemsData) ==1
                app.EvolutionFieldDropDown.Enable = 0;
            else
                app.EvolutionFieldDropDown.Enable = 1;
            end
            
            app.FFTDropDown.Value =  num2str(app.scan_data{app.currentscan}.fft_size);
            update3DFFTDropDown(app);
            app.WindowSizeEditField.Value = app.scan_data{app.currentscan}.window_size;
            
            if strcmp(app.scan_data{app.currentscan}.window_function, 'none')
                app.scan_data{app.currentscan}.window_function = 'None';
            end
            app.WindowDropDown.Value = app.scan_data{app.currentscan}.window_function;
            
            if isempty(app.scan_data{app.currentscan}.denoise_filter)
                app.scan_data{app.currentscan}.denoise_filter = 'None';
            end

            if isempty(app.scan_data{app.currentscan}.denoise_params) || ...
                    ~isstruct(app.scan_data{app.currentscan}.denoise_params)
                app.scan_data{app.currentscan}.denoise_params = ...
                    ffc_default_denoise_params(app.scan_data{app.currentscan}.denoise_filter);
            end

            app.FilterSettingsButton.Enable = 'on';
            app.FilterSettingsButton.Tooltip = {sprintf('Current denoise filter: %s', ...
                app.scan_data{app.currentscan}.denoise_filter)};

            nCh = app.scan_data{app.currentscan}.n_receivers;
            selectedReceivers = app.scan_data{app.currentscan}.multichannel_recon;
            if isempty(selectedReceivers)
                selectedReceivers = 1:nCh;
            else
                selectedReceivers = unique(round(double(selectedReceivers(:).')), 'stable');
                selectedReceivers = selectedReceivers(isfinite(selectedReceivers) & ...
                    selectedReceivers >= 1 & selectedReceivers <= nCh);
                if isempty(selectedReceivers)
                    selectedReceivers = 1:nCh;
                end
            end
            app.scan_data{app.currentscan}.multichannel_recon = selectedReceivers;

            if isempty(app.scan_data{app.currentscan}.biascorrect) || ...
                    ~ismember(string(app.scan_data{app.currentscan}.biascorrect), ["Off", "ACS"])
                app.scan_data{app.currentscan}.biascorrect = 'Off';
            end
            app.BiasFieldDropDown.Value = app.scan_data{app.currentscan}.biascorrect;

            if isempty(app.scan_data{app.currentscan}.combination) || ...
                    ~ismember(string(app.scan_data{app.currentscan}.combination), ...
                    ["RSSQ", "Adaptive", "ACS/Walsh"])
                app.scan_data{app.currentscan}.combination = 'Adaptive';
            end
            app.CombinationDropDown.Value = app.scan_data{app.currentscan}.combination;

            whiteningCheckBox = getNoiseWhiteningCheckBox(app);
            if nCh <= 1
                app.scan_data{app.currentscan}.noise_whitening = false;
                whiteningCheckBox.Value = false;
                whiteningCheckBox.Enable = 'off';
                whiteningCheckBox.Tooltip = {'Noise whitening requires at least two acquired receive channels.'};
                app.CombinationDropDown.Enable = 'off';
                app.CombinationDropDownLabel.Enable = 'off';
            else
                if isempty(app.scan_data{app.currentscan}.noise_whitening)
                    app.scan_data{app.currentscan}.noise_whitening = true;
                end
                whiteningCheckBox.Value = logical(app.scan_data{app.currentscan}.noise_whitening);
                whiteningCheckBox.Enable = 'on';
                whiteningCheckBox.Tooltip = {'Prewhiten all acquired receivers, then reconstruct only the selected receiver IDs.'};
                app.CombinationDropDown.Enable = 'on';
                app.CombinationDropDownLabel.Enable = 'on';
            end

            app.SliceDropDown.Value = 1;
            app.EvolutionFieldDropDown.Value =1;
            app.EvolutionTimeDropDown.Value =1;
            app.MultichannelDropDown.Items     = string(0:(nCh-1));
            app.MultichannelDropDown.ItemsData = 1:nCh;
            app.MultichannelDropDown.Value     = selectedReceivers;
            %kspace to image space with selected recon parameters
            app.scan_data{app.currentscan} = buildimages(app.scan_data{app.currentscan});
            updateSliceDropDownFromImages(app);
         img0 = app.scan_data{app.currentscan}.magimage(:,:,1,1,1);
autoWindowFromImage(app, img0);
            
            drawimages(app)


        end

        function checkBox = getNoiseWhiteningCheckBox(app)
            if ~isappdata(app.panel_recon, 'NoiseWhiteningCheckBox')
                error('FFCProcessing:MissingWhiteningControl', ...
                    'The noise-whitening control has not been initialised.');
            end
            checkBox = getappdata(app.panel_recon, 'NoiseWhiteningCheckBox');
        end

        function applyImageFovGeometry(app)
            obj = app.scan_data{app.currentscan};
            fovRead = double(obj.fov);
            fovPhase = double(obj.fov_phase);

            if isempty(fovRead) || ~isscalar(fovRead) || ~isfinite(fovRead) || fovRead <= 0
                return;
            end
            if isempty(fovPhase) || ~isscalar(fovPhase) || ...
                    ~isfinite(fovPhase) || fovPhase <= 0
                fovPhase = fovRead;
            end

            % Rotating a rectangular image by 90 degrees swaps its displayed
            % read and phase extents.
            if ~isempty(obj.rot_theta) && mod(round(double(obj.rot_theta)), 180) == 90
                temp = fovRead;
                fovRead = fovPhase;
                fovPhase = temp;
            end

            imageHandles = findobj(app.axis_image, 'Type', 'Image');
            if isempty(imageHandles)
                return;
            end
            set(imageHandles, 'XData', [-fovPhase/2, fovPhase/2], ...
                'YData', [-fovRead/2, fovRead/2]);
            axis(app.axis_image, 'image');
            axis(app.axis_image, 'tight');
        end
        
        function autoWindowFromImage(app, img)
    x = double(img(:));
    x = x(isfinite(x));

    if isempty(x)
        return;
    end

    pLo = prctile(x, 1);
    pHi = prctile(x, 99);

    if pHi <= pLo
        pLo = min(x);
        pHi = max(x);
        if pHi <= pLo
            pHi = pLo + 1;
        end
    end

    WL = (pLo + pHi)/2;
    WW = max(pHi - pLo, eps);

    % Set slider limits wide enough that you can “push” past auto-window
    app.WindowLevelSlider.Limits = [pLo - 0.5*WW, pHi + 0.5*WW];  % WL range
    app.WindowWidthSlider.Limits   = [WW*0.01, WW*5];               % WW range

    app.WindowLevelSlider.Value = WL;
    app.WindowWidthSlider.Value   = WW;
end 
        
function update3DFFTDropDown(app)

    obj = app.scan_data{app.currentscan};

    nSlicesAcquired = obj.slices;

    % Useful through-plane zero-fill choices: acquired size, next power of
    % two, and 2x acquired size. The previous +1/+2 choices added almost no
    % interpolation while still changing odd/even centring.
    fft3dVals = unique([nSlicesAcquired, ...
        2^nextpow2(nSlicesAcquired), 2*nSlicesAcquired], 'stable');

    app.FFTDropDown_3D.Items = cellstr(string(fft3dVals));
    app.FFTDropDown_3D.ItemsData = fft3dVals;

    % Initialise obj.fft_size_3d if missing/empty
    needsDefault = false;

    try
        if ~isfield(obj, 'fft_size_3d') || isempty(obj.fft_size_3d)
            needsDefault = true;
        end
    catch
        try
            if ~isprop(obj, 'fft_size_3d') || isempty(obj.fft_size_3d)
                needsDefault = true;
            end
        catch
            needsDefault = true;
        end
    end

    if needsDefault
        obj.fft_size_3d = nSlicesAcquired;
        app.scan_data{app.currentscan} = obj;
    end

    % If the stored value is not one of the default options, include it
    currentVal = obj.fft_size_3d;

    if ~ismember(currentVal, fft3dVals)
        fft3dVals = unique([fft3dVals, currentVal], 'stable');
        app.FFTDropDown_3D.Items = cellstr(string(fft3dVals));
        app.FFTDropDown_3D.ItemsData = fft3dVals;
    end

    app.FFTDropDown_3D.Value = currentVal;

    % Only enable for 3D scans
    if obj.TwoDimensional == 1 || nSlicesAcquired <= 1
        app.FFTDropDown_3D.Enable = 'off';
        app.Sizeof3DFFTLabel.Enable = 'off';
    else
        app.FFTDropDown_3D.Enable = 'on';
        app.Sizeof3DFFTLabel.Enable = 'on';
    end

end

function updateSliceDropDownFromImages(app)

    obj = app.scan_data{app.currentscan};

    if isempty(obj.magimage)
        nSlicesDisplay = obj.slices;
    else
        nSlicesDisplay = size(obj.magimage, 3);
    end

    oldSlice = 1;
    if ~isempty(app.SliceDropDown.Value)
        oldSlice = app.SliceDropDown.Value;
    end

    app.SliceDropDown.Items = cellstr(string(1:nSlicesDisplay));
    app.SliceDropDown.ItemsData = 1:nSlicesDisplay;
    app.SliceDropDown.Value = min(oldSlice, nSlicesDisplay);

    if nSlicesDisplay <= 1
        app.SliceDropDown.Enable = 'off';
    else
        app.SliceDropDown.Enable = 'on';
    end

end

        function drawimages(app)
            axis(app.axis_image,'image'); %forces image axis to rescale to the image matrix size
            axis(app.axis_kspace,'image');
            caxis(app.axis_image,'auto'); %initially use automatic colourscale, we can change to defined limits later if desired
            
            %get display params from the sliders
            WL = app.WindowLevelSlider.Value;  % window level
            WW = app.WindowWidthSlider.Value;    % window width
            
            %get the currently selected field/time/slice
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;
            
            absolute_scale = app.IndividualNormalisationButton.Value; %how will the image intensity be scaled?
            
            ImageType = app.ImageTypeDropDown.Value; %magnitude, phase or map
            
kspaceSlice = min(disp_slice, size(app.scan_data{app.currentscan}.magkspace, 3));

displayedkspace = log(1 + app.scan_data{app.currentscan}.magkspace(:,:, ...
    kspaceSlice, disp_time, disp_field));            
            switch ImageType
                case "Magnitude"
                    displayedimage = double(app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field));

    % PACS windowing: map to [0,1] using WL/WW
    low  = WL - WW/2;
    high = WL + WW/2;
    if high <= low
        high = low + eps;
    end
    displayedimage = (displayedimage - low) ./ (high - low);
    displayedimage = min(max(displayedimage, 0), 1);

    colormap(app.axis_image, gray);
    colormap(app.axis_kspace, gray);
                    
                case "Phase"
                    displayedimage = double(app.scan_data{app.currentscan}.phaseimage(:,:,disp_slice,disp_time,disp_field));
    displayedimage = (displayedimage - (-pi)) / (2*pi);
    displayedimage = min(max(displayedimage, 0), 1);
    colormap(app.axis_image, gray);
    colormap(app.axis_kspace, gray);
                    
                case "Relaxometry Map"
                    app.scan_data{app.currentscan}.R1T1 = app.DisplayingasT1Button.Value; %T1 or R1
                    caxis(app.axis_image,[app.ColourAxisLowerLimitEditField.Value,app.ColourAxisUpperLimitEditField.Value]); %get the limits from the GUI
                    colormap(app.axis_kspace,'gray'); %keep kspace grayscale
                    colormap(app.axis_image,'jet');
                    if app.scan_data{app.currentscan}.R1T1 == 0
                        if app.LogarithmicMapsButton.Value ==0
                            displayedimage = app.scan_data{app.currentscan}.T1Maps(:,:,disp_slice,disp_field);
                        else
                            displayedimage = log(app.scan_data{app.currentscan}.T1Maps(:,:,disp_slice,disp_field));
                        end
                    else
                        if app.LogarithmicMapsButton.Value ==0
                            displayedimage = app.scan_data{app.currentscan}.R1Maps(:,:,disp_slice,disp_field);
                        else
                            displayedimage = log(app.scan_data{app.currentscan}.R1Maps(:,:,disp_slice,disp_field));
                        end
                    end
            end
            
            if absolute_scale  && ImageType == "Magnitude" %only do imshow scaling on mag images - doesn't make sense for phase and relaxation maps have their own scaling
                max_level = max(app.scan_data{app.currentscan}.magimage(:));
                imshow(displayedimage,[0 255],'Parent',app.axis_image); %0.2 * max is a reasonable guess but should revisit this
                imagesc(app.axis_image,displayedimage);
            else
                
                imagesc(app.axis_kspace, displayedkspace);
                imagesc(app.axis_image,displayedimage);
            end

            applyImageFovGeometry(app);
            
            
% ---- Orientation compass ----

% ---- Orientation compass using Image_Orientation_subject ----
ios = [];

    ios = app.scan_data{app.currentscan}.orientation;


cla(app.axis_coordinates);
hold(app.axis_coordinates,'on');
axis(app.axis_coordinates,[-1.2 1.2 -1.2 1.2 -1.2 1.2]);
axis(app.axis_coordinates,'off');
grid(app.axis_coordinates,'off');

% If we don't have a valid 1x6 (or 6x1) orientation, just draw a default triad
if isempty(ios) || numel(ios) ~= 6
    % Default: L/R along X, P/A along Y, H/F along Z in this compass space
    L_img = [ 1; 0; 0];  R_img = -L_img;
    P_img = [ 0; 1; 0];  A_img = -P_img;
    H_img = [ 0; 0; 1];  F_img = -H_img;
else
    % ios = [rowDir(1:3)  colDir(1:3)] in patient coordinates
    ios = ios(:);            % force to 6x1 column
    ios = ios(1:6);          % just in case

    R_pat = ios(1:3);        % 3x1 column (row direction in patient space)
    C_pat = ios(4:6);        % 3x1 column (column direction in patient space)

    % Ensure unit vectors
    R_pat = R_pat ./ norm(R_pat);
    C_pat = C_pat ./ norm(C_pat);
    N_pat = cross(R_pat, C_pat);      % slice normal in patient coords
    N_pat = N_pat ./ norm(N_pat);
R_pat_disp = C_pat;
C_pat_disp = R_pat;
    % Rotation matrix: image -> patient (columns are basis vectors)
    M = [R_pat_disp, C_pat_disp, N_pat];      % 3x3

    % Inverse (patient -> image). For rotation, inverse is transpose.
    Minv = M.';                       % 3x3

    % Patient basis (DICOM): +X=L, +Y=P, +Z=H
    eL_pat = [ -1; 0; 0];
    eP_pat = [ 0; 1; 0];
    eH_pat = [ 0; 0; -1];

    % Directions of patient axes in image-coordinate compass space
    uLR = Minv * eL_pat;              % direction of L
    uPA = Minv * eP_pat;              % direction of P
    uHF = Minv * eH_pat;              % direction of H

    % Opposite directions
    L_img =  uLR;   R_img = -uLR;
    P_img =  uPA;   A_img = -uPA;
    H_img =  uHF;   F_img = -uHF;
end

% --- Apply accumulated image-plane transform (rotations + flips) ---
    T = app.scan_data{app.currentscan}.geomT;


L_img = T * L_img;
R_img = T * R_img;
P_img = T * P_img;
A_img = T * A_img;
H_img = T * H_img;
F_img = T * F_img;

% Plot the three axes as lines
plot3(app.axis_coordinates, [L_img(1) R_img(1)], ...
                             [L_img(2) R_img(2)], ...
                             [L_img(3) R_img(3)], 'LineWidth', 3); % L–R

plot3(app.axis_coordinates, [P_img(1) A_img(1)], ...
                             [P_img(2) A_img(2)], ...
                             [P_img(3) A_img(3)], 'LineWidth', 3); % P–A

plot3(app.axis_coordinates, [F_img(1) H_img(1)], ...
                             [F_img(2) H_img(2)], ...
                             [F_img(3) H_img(3)], 'LineWidth', 3); % F–H

% Label the endpoints slightly beyond the tips
scale = 1.15;
text(app.axis_coordinates, L_img(1)*scale, L_img(2)*scale, L_img(3)*scale, ...
     'L', 'FontWeight','Bold','FontSize',14);
text(app.axis_coordinates, R_img(1)*scale, R_img(2)*scale, R_img(3)*scale, ...
     'R', 'FontWeight','Bold','FontSize',14);

text(app.axis_coordinates, P_img(1)*scale, P_img(2)*scale, P_img(3)*scale, ...
     'P', 'FontWeight','Bold','FontSize',14);
text(app.axis_coordinates, A_img(1)*scale, A_img(2)*scale, A_img(3)*scale, ...
     'A', 'FontWeight','Bold','FontSize',14);

text(app.axis_coordinates, H_img(1)*scale, H_img(2)*scale, H_img(3)*scale, ...
     'H', 'FontWeight','Bold','FontSize',14);
text(app.axis_coordinates, F_img(1)*scale, F_img(2)*scale, F_img(3)*scale, ...
     'F', 'FontWeight','Bold','FontSize',14);

hold(app.axis_coordinates,'off');

drawnow


        end
        
        function updateimages(app)  %this method reconstructs the images in case of a change of filter or fft size - more time consuming than a simple redraw
            app.scan_data{app.currentscan}.fft_size = str2double(app.FFTDropDown.Value);
            app.scan_data{app.currentscan}.fft_size_3d = str2double(string(app.FFTDropDown_3D.Value));
            app.scan_data{app.currentscan}.window_size = app.WindowSizeEditField.Value;
            app.scan_data{app.currentscan}.window_function = app.WindowDropDown.Value;
            app.scan_data{app.currentscan}.multichannel_recon = app.MultichannelDropDown.Value;
            app.scan_data{app.currentscan}.combination = app.CombinationDropDown.Value;
            app.scan_data{app.currentscan}.biascorrect = app.BiasFieldDropDown.Value;
            whiteningCheckBox = getNoiseWhiteningCheckBox(app);
            app.scan_data{app.currentscan}.noise_whitening = ...
                app.scan_data{app.currentscan}.n_receivers > 1 && ...
                logical(whiteningCheckBox.Value);
            app.scan_data{app.currentscan} = buildimages(app.scan_data{app.currentscan});
updateSliceDropDownFromImages(app);
img0 = app.scan_data{app.currentscan}.magimage(:,:,app.SliceDropDown.Value, ...
                                              app.EvolutionTimeDropDown.Value, ...
                                              app.EvolutionFieldDropDown.Value);
autoWindowFromImage(app, img0);

            drawimages(app)
            
        end
    
    function s = mergeStruct(app, s, override)
    if isempty(override) || ~isstruct(override)
        return
    end

    fn = fieldnames(override);
    for k = 1:numel(fn)
        if ~isempty(override.(fn{k}))
            s.(fn{k}) = override.(fn{k});
        end
    end
end

function redrawCurrentMagImage(app, doAutoWindow)

    if nargin < 2
        doAutoWindow = true;
    end

    if doAutoWindow
        img0 = app.scan_data{app.currentscan}.magimage(:,:, ...
            app.SliceDropDown.Value, ...
            app.EvolutionTimeDropDown.Value, ...
            app.EvolutionFieldDropDown.Value);

        autoWindowFromImage(app, img0);
    end

    drawimages(app);

end

function [baseMag, filterType, params] = getCurrentFilterState(app)
    obj = app.scan_data{app.currentscan};

    filterType = obj.denoise_filter;
    if isempty(filterType)
        filterType = 'None';
    end

    params = ffc_default_denoise_params(filterType);
    params = mergeStruct(app, params, obj.denoise_params);

    if app.filter_preview_active && isequal(app.filter_preview_scan, app.currentscan) ...
            && ~isempty(app.filter_preview_original)
        baseMag = app.filter_preview_original;
    else
        baseMag = obj.magimage;
    end
end

function beginFilterPreview(app, baseMag)
    if ~app.filter_preview_active || isempty(app.filter_preview_scan) || ...
            app.filter_preview_scan ~= app.currentscan
        app.filter_preview_original = baseMag;
        app.filter_preview_scan = app.currentscan;
        app.filter_preview_active = true;
    end
end

function clearFilterPreview(app)
    app.filter_preview_original = [];
    app.filter_preview_scan = [];
    app.filter_preview_active = false;
end

function restoreFilterPreview(app)
    if app.filter_preview_active && isequal(app.filter_preview_scan, app.currentscan) ...
            && ~isempty(app.filter_preview_original)
        obj = app.scan_data{app.currentscan};
        obj.magimage = app.filter_preview_original;
        app.scan_data{app.currentscan} = obj;
        clearFilterPreview(app);
        redrawCurrentMagImage(app);
    end
end

function updateFilterButtonTooltip(app)
    obj = app.scan_data{app.currentscan};
    if isempty(obj.denoise_filter)
        filt = 'None';
    else
        filt = obj.denoise_filter;
    end
    app.FilterSettingsButton.Tooltip = {sprintf('Current denoise filter: %s', filt)};
end
    
function openFilterSubGui(app)

    obj = app.scan_data{app.currentscan};
    baseMag = obj.magimage;

    % Start with the same windowing as the main GUI
    previewWL = app.WindowLevelSlider.Value;
    previewWW = app.WindowWidthSlider.Value;

    % Store last calculated preview so WL/WW changes do not rerun the filter
    lastPreviewImg = [];
    lastFilteredPreview = [];

    % Use stored params if they exist, otherwise sensible defaults.
    % The filter itself is still chosen inside this sub-GUI.
    if isstruct(obj.denoise_params)
        currentParams = mergeStruct(app, ffc_default_denoise_params('LLR+Wavelet'), obj.denoise_params);
    else
        currentParams = ffc_default_denoise_params('LLR+Wavelet');
    end

    filterItems = {'None', ...
                   'Wavelet', ...
                   'LLR', ...
                   'LLR+Wavelet', ...
                   'Total Variation', ...
                   'Generalised Total Variation', ...
                   'LLR+Wavelet+TGV'};

    defaultFilterInSubGui = 'LLR+Wavelet';

    sz = size(baseMag);
    if numel(sz) < 5
        sz = [sz ones(1, 5-numel(sz))];
    end

    nSlices = sz(3);
    nTimes  = sz(4);
    nFields = sz(5);

    dlg = uifigure('Name', 'Filter Settings', ...
        'Position', [100 100 1080 640]);

    axOrig = uiaxes(dlg, 'Position', [30 230 450 370]);
    title(axOrig, 'Original');

    axFilt = uiaxes(dlg, 'Position', [590 230 450 370]);
    title(axFilt, 'Filtered preview');

    % ---------------------------------------------------------------------
    % Filter and stack selection controls
    % ---------------------------------------------------------------------

    uilabel(dlg, 'Position', [30 190 100 22], 'Text', 'Filter type');

    ddType = uidropdown(dlg, ...
        'Position', [130 190 190 22], ...
        'Items', filterItems, ...
        'Value', defaultFilterInSubGui);

    uilabel(dlg, 'Position', [340 190 40 22], 'Text', 'Slice');
    ddSlice = uidropdown(dlg, ...
        'Position', [380 190 60 22], ...
        'Items', cellstr(string(1:nSlices)), ...
        'ItemsData', 1:nSlices, ...
        'Value', min(app.SliceDropDown.Value, nSlices));

    uilabel(dlg, 'Position', [460 190 40 22], 'Text', 'Time');
    ddTime = uidropdown(dlg, ...
        'Position', [500 190 60 22], ...
        'Items', cellstr(string(1:nTimes)), ...
        'ItemsData', 1:nTimes, ...
        'Value', min(app.EvolutionTimeDropDown.Value, nTimes));

    uilabel(dlg, 'Position', [580 190 40 22], 'Text', 'Field');
    ddField = uidropdown(dlg, ...
        'Position', [620 190 60 22], ...
        'Items', cellstr(string(1:nFields)), ...
        'ItemsData', 1:nFields, ...
        'Value', min(app.EvolutionFieldDropDown.Value, nFields));

    % ---------------------------------------------------------------------
    % Windowing controls
    % ---------------------------------------------------------------------

    x = double(baseMag(:));
    x = x(isfinite(x));

    if isempty(x)
        x = [0; 1];
    end

    pLo = prctile(x, 1);
    pHi = prctile(x, 99);

    if pHi <= pLo
        pLo = min(x);
        pHi = max(x);
        if pHi <= pLo
            pHi = pLo + 1;
        end
    end

    autoWL = (pLo + pHi) / 2;
    autoWW = max(pHi - pLo, eps);

    wlLimits = [pLo - 0.5*autoWW, pHi + 0.5*autoWW];
    wwLimits = [autoWW*0.01, autoWW*5];

    previewWL = min(max(previewWL, wlLimits(1)), wlLimits(2));
    previewWW = min(max(previewWW, wwLimits(1)), wwLimits(2));

    uilabel(dlg, 'Position', [710 195 90 22], 'Text', 'Window level');
    slWL = uislider(dlg, ...
        'Position', [800 205 230 3], ...
        'Limits', wlLimits, ...
        'Value', previewWL);

    uilabel(dlg, 'Position', [710 160 90 22], 'Text', 'Window width');
    slWW = uislider(dlg, ...
        'Position', [800 170 230 3], ...
        'Limits', wwLimits, ...
        'Value', previewWW);

    % ---------------------------------------------------------------------
    % Denoising parameter controls
    % ---------------------------------------------------------------------

    uilabel(dlg, 'Position', [30 145 80 22], 'Text', 'Patch');
    spPatch = uispinner(dlg, ...
        'Position', [90 145 60 22], ...
        'Limits', [3 31], ...
        'Step', 1, ...
        'Value', currentParams.patch);

    uilabel(dlg, 'Position', [170 145 80 22], 'Text', 'Step');
    spStep = uispinner(dlg, ...
        'Position', [220 145 60 22], ...
        'Limits', [1 16], ...
        'Step', 1, ...
        'Value', currentParams.step);

    uilabel(dlg, 'Position', [300 145 80 22], 'Text', 'LLR iter');
    spNIter = uispinner(dlg, ...
        'Position', [360 145 60 22], ...
        'Limits', [1 10], ...
        'Step', 1, ...
        'Value', currentParams.niter);

    uilabel(dlg, 'Position', [440 145 80 22], 'Text', 'Tau');
    edTau = uieditfield(dlg, 'numeric', ...
        'Position', [480 145 70 22], ...
        'Value', currentParams.tau);

    uilabel(dlg, 'Position', [570 145 90 22], 'Text', 'Wavelet lvl');
    spWav = uispinner(dlg, ...
        'Position', [650 145 60 22], ...
        'Limits', [1 6], ...
        'Step', 1, ...
        'Value', currentParams.wavelet_level);

    uilabel(dlg, 'Position', [30 110 80 22], 'Text', 'TGV a0');
    edA0 = uieditfield(dlg, 'numeric', ...
        'Position', [90 110 70 22], ...
        'Value', currentParams.tgv_alpha0);

    uilabel(dlg, 'Position', [180 110 80 22], 'Text', 'TGV a1');
    edA1 = uieditfield(dlg, 'numeric', ...
        'Position', [240 110 70 22], ...
        'Value', currentParams.tgv_alpha1);

    uilabel(dlg, 'Position', [330 110 90 22], 'Text', 'TGV lambda');
    edLam = uieditfield(dlg, 'numeric', ...
        'Position', [410 110 70 22], ...
        'Value', currentParams.tgv_lambda);

    uilabel(dlg, 'Position', [500 110 90 22], 'Text', 'TGV iter');
    spTGVIter = uispinner(dlg, ...
        'Position', [570 110 70 22], ...
        'Limits', [10 1000], ...
        'Step', 10, ...
        'Value', currentParams.tgv_niter);

    % ---------------------------------------------------------------------
    % Buttons
    % ---------------------------------------------------------------------
    btnAutoTGV = uibutton(dlg, 'push', ...
    'Position', [800 90 150 32], ...
    'Text', 'Auto TGV');


    btnPreview = uibutton(dlg, 'push', ...
        'Position', [30 55 130 32], ...
        'Text', 'Preview');

    btnApply = uibutton(dlg, 'push', ...
        'Position', [180 55 180 32], ...
        'Text', 'Apply to current set');

    btnPermanent = uibutton(dlg, 'push', ...
        'Position', [380 55 230 32], ...
        'Text', 'Permanently apply to dataset');

    btnResetParams = uibutton(dlg, 'push', ...
        'Position', [630 55 150 32], ...
        'Text', 'Reset parameters');

    btnMatchMainWindow = uibutton(dlg, 'push', ...
        'Position', [800 55 150 32], ...
        'Text', 'Match main window');

    btnClose = uibutton(dlg, 'push', ...
        'Position', [960 55 80 32], ...
        'Text', 'Close');

    statusLabel = uilabel(dlg, ...
        'Position', [30 20 1000 22], ...
        'Text', 'Choose filter and press Preview');

    % ---------------------------------------------------------------------
    % Callbacks
    % ---------------------------------------------------------------------

    ddType.ValueChangedFcn   = @(~,~) filterTypeChanged();
    ddSlice.ValueChangedFcn  = @(~,~) updatePreview();
    ddTime.ValueChangedFcn   = @(~,~) updatePreview();
    ddField.ValueChangedFcn  = @(~,~) updatePreview();

    btnPreview.ButtonPushedFcn         = @(~,~) updatePreview();
    btnApply.ButtonPushedFcn           = @(~,~) applyToCurrentSet();
    btnPermanent.ButtonPushedFcn       = @(~,~) permanentlyApply();
    btnResetParams.ButtonPushedFcn     = @(~,~) resetFilterParameters();
    btnMatchMainWindow.ButtonPushedFcn = @(~,~) matchMainWindow();
    btnClose.ButtonPushedFcn           = @(~,~) delete(dlg);
    btnAutoTGV.ButtonPushedFcn = @(~,~) autoTGVParams();

    % Windowing should not rerun the filter
    slWL.ValueChangedFcn = @(~,~) updatePreviewWindowOnly();
    slWW.ValueChangedFcn = @(~,~) updatePreviewWindowOnly();

    % More responsive while dragging, if your MATLAB version supports it
    slWL.ValueChangingFcn = @(src,event) windowChanging(src, event);
    slWW.ValueChangingFcn = @(src,event) windowChanging(src, event);

    updateControlEnableState();
    updatePreview();

    % =====================================================================
    % Nested functions
    % =====================================================================

    function filterTypeChanged()
        resetFilterParameters();
    end
function autoTGVParams()

    strength = 1.0;  % moderate default

    % Estimate from the same selected-slice stack used for preview
    selectedSlice = ddSlice.Value;
    estStack = baseMag(:,:,selectedSlice,:,:);

    p = ffc_estimate_tgv_params(estStack, strength);

    edA0.Value = p.tgv_alpha0;
    edA1.Value = p.tgv_alpha1;
    edLam.Value = p.tgv_lambda;
    spTGVIter.Value = p.tgv_niter;

    statusLabel.Text = sprintf( ...
        'Auto TGV: sigmaRel %.4g, a0 %.4g, a1 %.4g, lambda %.4g', ...
        p.sigmaRel, p.tgv_alpha0, p.tgv_alpha1, p.tgv_lambda);

    updatePreview();

end
    function [filterType, params, previewStack, previewIndex] = readControls()

        filterType = ddType.Value;

        params = struct();

        params.patch = spPatch.Value;
        if mod(params.patch, 2) == 0
            params.patch = params.patch + 1;
            spPatch.Value = params.patch;
        end

        params.step = spStep.Value;
        params.niter = spNIter.Value;
        params.tau = edTau.Value;
        params.sigma = [];
        params.wavelet_level = spWav.Value;

        params.tgv_alpha0 = edA0.Value;
        params.tgv_alpha1 = edA1.Value;
        params.tgv_lambda = edLam.Value;
        params.tgv_niter = spTGVIter.Value;

params.tv_lambda = edLam.Value;
params.tv_niter = spTGVIter.Value;

        selectedSlice = ddSlice.Value;
        selectedTime  = ddTime.Value;
        selectedField = ddField.Value;

        % Filter a whole stack for the selected slice.
        % This gives LLR a meaningful third dimension.
        previewStack5D = baseMag(:,:,selectedSlice,:,:);

        stackSize = size(previewStack5D);
        if numel(stackSize) < 5
            stackSize = [stackSize ones(1, 5-numel(stackSize))];
        end

        previewStack = reshape(previewStack5D, stackSize(1), stackSize(2), []);

        % MATLAB column-major flattening of [time field] means time changes fastest.
        previewIndex = sub2ind([nTimes nFields], selectedTime, selectedField);

    end

    function updatePreview()

        updateControlEnableState();

        [filterType, params, previewStack, previewIndex] = readControls();

        try
            statusLabel.Text = 'Updating preview...';
            drawnow;

            filteredStack = ffc_mri_filter(previewStack, filterType, params);

            lastPreviewImg = previewStack(:,:,previewIndex);
            lastFilteredPreview = filteredStack(:,:,previewIndex);

            updatePreviewWindowOnly();

            title(axOrig, sprintf('Original slice %d', ddSlice.Value));
            title(axFilt, sprintf('Filtered preview: %s', filterType));

            statusLabel.Text = sprintf('Preview ready: filtered %d frames', size(previewStack,3));

        catch ME
            statusLabel.Text = ['Preview error: ' ME.message];
        end

    end

    function updatePreviewWindowOnly()

        if isempty(lastPreviewImg) || isempty(lastFilteredPreview)
            return
        end

        previewWL = slWL.Value;
        previewWW = slWW.Value;

        img1 = applyPreviewWindow(lastPreviewImg);
        img2 = applyPreviewWindow(lastFilteredPreview);

        imagesc(axOrig, img1);
        axis(axOrig, 'image');
        colormap(axOrig, gray);
        axOrig.XTick = [];
        axOrig.YTick = [];
        axOrig.CLim = [0 1];

        imagesc(axFilt, img2);
        axis(axFilt, 'image');
        colormap(axFilt, gray);
        axFilt.XTick = [];
        axFilt.YTick = [];
        axFilt.CLim = [0 1];

    end

    function imgW = applyPreviewWindow(img)

        img = double(img);

        low  = previewWL - previewWW/2;
        high = previewWL + previewWW/2;

        if high <= low
            high = low + eps;
        end

        imgW = (img - low) ./ (high - low);
        imgW = min(max(imgW, 0), 1);

    end

    function windowChanging(src, event)

        if src == slWL
            previewWL = event.Value;
        elseif src == slWW
            previewWW = event.Value;
        end

        updatePreviewWindowOnly();

    end

    function resetFilterParameters()

        filterType = ddType.Value;
        p = ffc_default_denoise_params(filterType);

        spPatch.Value = p.patch;
        spStep.Value = p.step;
        spNIter.Value = p.niter;
        edTau.Value = p.tau;
        spWav.Value = p.wavelet_level;

        if isfield(p, 'tgv_alpha0')
            edA0.Value = p.tgv_alpha0;
        end

        if isfield(p, 'tgv_alpha1')
            edA1.Value = p.tgv_alpha1;
        end

        if isfield(p, 'tgv_lambda')
            edLam.Value = p.tgv_lambda;
        end

        if isfield(p, 'tgv_niter')
            spTGVIter.Value = p.tgv_niter;
        end

        updateControlEnableState();
        updatePreview();

    end

    function matchMainWindow()

        previewWL = app.WindowLevelSlider.Value;
        previewWW = app.WindowWidthSlider.Value;

        previewWL = min(max(previewWL, slWL.Limits(1)), slWL.Limits(2));
        previewWW = min(max(previewWW, slWW.Limits(1)), slWW.Limits(2));

        slWL.Value = previewWL;
        slWW.Value = previewWW;

        updatePreviewWindowOnly();

    end

    function applyToCurrentSet()

        [filterType, params] = readControls();

        d = uiprogressdlg(dlg, ...
            'Title', 'Filtering', ...
            'Message', 'Applying filter to current image set...');

        drawnow;

        try
            filteredMag = ffc_mri_filter(baseMag, filterType, params);

            obj2 = app.scan_data{app.currentscan};
            obj2.magimage = filteredMag;

            % Store the settings used.
            obj2.denoise_filter = filterType;
            obj2.denoise_params = params;

            app.scan_data{app.currentscan} = obj2;

            % Preserve sub-GUI windowing in the main GUI
            app.WindowLevelSlider.Value = slWL.Value;
            app.WindowWidthSlider.Value = slWW.Value;

            drawimages(app);
            updateFilterButtonTooltip(app);

            statusLabel.Text = 'Applied to current image set';

        catch ME
            statusLabel.Text = ['Apply error: ' ME.message];
        end

        close(d);

    end

    function permanentlyApply()

        [filterType, params] = readControls();

        d = uiprogressdlg(dlg, ...
            'Title', 'Filtering', ...
            'Message', 'Permanently applying filter to dataset...');

        drawnow;

        try
            filteredMag = ffc_mri_filter(baseMag, filterType, params);

            obj2 = app.scan_data{app.currentscan};

            obj2.magimage = filteredMag;
            obj2.denoise_filter = filterType;
            obj2.denoise_params = params;

            app.scan_data{app.currentscan} = obj2;

            % Preserve sub-GUI windowing in the main GUI
            app.WindowLevelSlider.Value = slWL.Value;
            app.WindowWidthSlider.Value = slWW.Value;

            drawimages(app);
            updateFilterButtonTooltip(app);

            statusLabel.Text = 'Permanently applied to dataset';

        catch ME
            statusLabel.Text = ['Permanent apply error: ' ME.message];
        end

        close(d);

    end

    function updateControlEnableState()

        filterType = ddType.Value;

        usesLLR = ismember(filterType, ...
            {'LLR','LLR+Wavelet','LLR+Wavelet+TGV'});

        usesWavelet = ismember(filterType, ...
            {'Wavelet','LLR+Wavelet','LLR+Wavelet+TGV'});

        usesTGV = ismember(filterType, ...
            {'Generalised Total Variation','LLR+Wavelet+TGV'});

        usesTV = ismember(filterType, ...
            {'Total Variation'});

        llrState = matlab.lang.OnOffSwitchState(usesLLR);
        wavState = matlab.lang.OnOffSwitchState(usesWavelet);
        tgvState = matlab.lang.OnOffSwitchState(usesTGV || usesTV);

        spPatch.Enable = llrState;
        spStep.Enable = llrState;
        spNIter.Enable = llrState;
        edTau.Enable = llrState;

        spWav.Enable = wavState;

        edA0.Enable = tgvState;
        edA1.Enable = tgvState;
        edLam.Enable = tgvState;
        spTGVIter.Enable = tgvState;

    end

end   


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This control is created at run time so the existing App Designer
            % component model remains backwards compatible.
            whiteningCheckBox = uicheckbox(app.panel_recon);
            whiteningCheckBox.Text = 'Noise whitening';
            whiteningCheckBox.Value = false;
            whiteningCheckBox.Enable = 'off';
            whiteningCheckBox.Position = [11 68 145 22];
            whiteningCheckBox.Tooltip = {'Noise whitening requires multiple receive channels.'};
            whiteningCheckBox.ValueChangedFcn = ...
                @(source, event) noiseWhiteningValueChanged(app, source);
            setappdata(app.panel_recon, 'NoiseWhiteningCheckBox', whiteningCheckBox);
        end

        % Button pushed function: LoadDataSetButton
        function LoadDataSetButtonPushed(app, event)
            file = open_mrifile;
           
            for n=1:length(file)
                file{n} = rename_sequence(file{n});
                sequencelist{n} = file{n}.displayname; %#ok<AGROW>
                try
                    file{n}.backgroundselect = get(app.BackgroundselectionButton,'Value');
                    file{n}.reprocess = get(app.ReprocessingButton,'Value');
                    file{n}.checkfit = get(app.CheckfitsButton,'Value');
                    file{n} = preprocessing(file{n}); %sort out k-space for this sequence
                    file{n}.T1Maps = zeros(file{n}.samples, file{n}.views, ...
                        file{n}.slices, length(file{n}.fieldpoints));
                    file{n}.R1Maps = zeros(file{n}.samples, file{n}.views, ...
                        file{n}.slices, length(file{n}.fieldpoints));
                    saveList = file;
                catch ME
                    disp(['Error while processing sequence number ' num2str(n) ':'])
                    disp(ME)
                end
            end
          %  save('ProcessedImages.mat','saveList'); %after preprocessing we can save the data set so it doesn't have to be done again
            
            app.ScanList.Items = sequencelist;
            app.ScanList.ItemsData =1:numel(app.ScanList.Items); %allows us to get index of selected scan
            app.currentscan = 1; %start on scan 1
            app.scan_data = file; %save the scan to the GUI for easy access
            
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'on') %enable all the GUI options now that we have a data set to work on. Errors abound if there is no data set loaded and buttons are pushed!

            activeimage(app) %start drawing images
  

% Optional: make sure the figure can take focus
figure(app.UIFigure);
            
        end

        % Value changed function: WindowLevelSlider
        function WindowLevelSliderValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: WindowWidthSlider
        function WindowWidthSliderValueChanged(app, event)
          drawimages(app)
        end

        % Callback function
        function GammaSliderValueChanged(app, event)
        drawimages(app)
        end

        % Value changed function: ScanList
        function ScanListValueChanged(app, event)
            activeimage(app)
            
        end

        % Value changed function: SliceDropDown
        function SliceDropDownValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: FFTDropDown
        function FFTDropDownValueChanged(app, event)
            updateimages(app)
        end

        % Value changed function: EvolutionTimeDropDown
        function EvolutionTimeDropDownValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: EvolutionFieldDropDown
        function EvolutionFieldDropDownValueChanged(app, event)
            disp_field = app.EvolutionFieldDropDown.Value;
            tevol_list = sprintfc('%g',round(app.scan_data{app.currentscan}.timepoints(disp_field,:),2)); %update the evolution times
            app.EvolutionTimeDropDown.Items = tevol_list;
            app.EvolutionTimeDropDown.ItemsData = 1:numel(app.EvolutionTimeDropDown.Items);
            drawimages(app)
        end

        % Value changed function: ImageTypeDropDown
        function ImageTypeDropDownValueChanged(app, event)
            drawimages(app)
        end

        % Value changed function: WindowDropDown
        function WindowDropDownValueChanged(app, event)
            updateimages(app)
        end

        % Value changed function: WindowSizeEditField
        function WindowSizeEditFieldValueChanged(app, event)
            updateimages(app)
        end

        % Button pushed function: GenerateROIDispersionCurveButton
        function GenerateROIDispersionCurveButtonPushed(app, event)

    % Delete previous ROI safely
    try
        if ~isempty(app.mask) && isvalid(app.mask)
            delete(app.mask);
        end
    catch
    end

    % --- Temporarily disable custom mouse handlers so drawfreehand can work ---
    f = app.UIFigure;

    oldDown  = f.WindowButtonDownFcn;
    oldMove  = f.WindowButtonMotionFcn;
    oldUp    = f.WindowButtonUpFcn;

    % Scroll wheel is optional depending on what you added
    oldWheel = [];
    if isprop(f,'WindowScrollWheelFcn')
        oldWheel = f.WindowScrollWheelFcn;
    end

    f.WindowButtonDownFcn = [];
    f.WindowButtonMotionFcn = [];
    f.WindowButtonUpFcn = [];
    if isprop(f,'WindowScrollWheelFcn')
        f.WindowScrollWheelFcn = [];
    end

    % Give focus to the figure (helps interaction)
    figure(f);

    % --- Draw ROI (blocking until completed/double-click) ---
    app.mask = drawfreehand(app.axis_image);

    % --- Restore handlers ---
    f.WindowButtonDownFcn = oldDown;
    f.WindowButtonMotionFcn = oldMove;
    f.WindowButtonUpFcn = oldUp;
    if isprop(f,'WindowScrollWheelFcn')
        f.WindowScrollWheelFcn = oldWheel;
    end

    % If user cancelled / ROI invalid, bail out cleanly
    if isempty(app.mask) || ~isvalid(app.mask)
        return;
    end

    % Continue your pipeline
    app.scan_data{app.currentscan}.mask = createMask(app.mask);
    app.scan_data{app.currentscan}.checkfit = app.CheckfitsButton.Value;
    app.scan_data{app.currentscan}.R1T1 = app.DisplayingasT1Button.Value;
    app.scan_data{app.currentscan}.dispersioncurve = T1dispersion( ...
        app.scan_data{app.currentscan}, app.SliceDropDown.Value);
        end

        % Button pushed function: GenerateRelaxationMapsButton
        function GenerateRelaxationMapsButtonPushed(app, event)
            app.scan_data{app.currentscan}.checkfit = app.CheckfitsButton.Value;
            app.scan_data{app.currentscan}.R1T1 = app.DisplayingasT1Button.Value;
            app.scan_data{app.currentscan} = MapRelaxation(app.scan_data{app.currentscan});
            drawimages(app)
        end

        % Button pushed function: RotateImageButton
        function RotateImageButtonPushed(app, event)
            theta = 90; %rot90 would work here but defining an affine transformation might be useful for future methods like registration/rescaling

ct = cosd(theta);
    st = sind(theta);
    Rz = [ ct  st  0;
          -st  ct  0;
           0   0  1];

    % Accumulate geometric transform: new T = Rz * old T
    
    app.scan_data{app.currentscan}.geomT = Rz * app.scan_data{app.currentscan}.geomT;


            tform_rotation = affine2d([...
                cosd(theta) sind(theta) 0;...
                -sind(theta) cosd(theta) 0; ...
                0 0 1]);
            app.scan_data{app.currentscan}.rot_theta =   mod((app.scan_data{app.currentscan}.rot_theta + 90),360); %not currently used but a future update will allow the undoing of geometric warping so useful to keep track of it
            %             invtheta = -1*app.scan_data{app.currentscan}.rot_theta;
            %             tform_undo_rotation = affine2d([...
            %                 cosd( invtheta) sind( invtheta) 0;...
            %                 -sind( invtheta) cosd( invtheta) 0; ...
            %                 0 0 1]);
            app.scan_data{app.currentscan}.originalcomplexkspace = imwarp(app.scan_data{app.currentscan}.originalcomplexkspace,tform_rotation);
            app.scan_data{app.currentscan}.complexkspace = imwarp(app.scan_data{app.currentscan}.complexkspace,tform_rotation);
            app.scan_data{app.currentscan}.compleximage = imwarp(app.scan_data{app.currentscan}.compleximage,tform_rotation);
            app.scan_data{app.currentscan}.magimage = imwarp(app.scan_data{app.currentscan}.magimage,tform_rotation);
            app.scan_data{app.currentscan}.phaseimage = imwarp( app.scan_data{app.currentscan}.phaseimage,tform_rotation);
            app.scan_data{app.currentscan}.T1Maps = imwarp(app.scan_data{app.currentscan}.T1Maps,tform_rotation);
            app.scan_data{app.currentscan}.R1Maps = imwarp(app.scan_data{app.currentscan}.R1Maps,tform_rotation);
            drawimages(app)
        end

        % Callback function
        function FilterDropDownValueChanged(app, event)
    
        end

        % Callback function
        function FilterControlEditFieldValueChanged(app, event)
    
            
        end

        % Value changed function: ColourAxisUpperLimitEditField
        function ColourAxisUpperLimitEditFieldValueChanged(app, event)
         %defines the limits of T1 or R1 that are displayed in the
         %relaxation maps
            if app.ColourAxisUpperLimitEditField.Value < app.ColourAxisLowerLimitEditField.Value
                app.ColourAxisUpperLimitEditField.Value = app.ColourAxisLowerLimitEditField.Value + 1; %stops errors when upperbound is less than lowerbound or vice versa
            end
            
            if app.ColourAxisLowerLimitEditField.Value > app.ColourAxisUpperLimitEditField.Value
                app.ColourAxisLowerLimitEditField.Value = app.ColourAxisUpperLimitEditField.Value - 1;
            end
            
            drawimages(app)
        end

        % Value changed function: ColourAxisLowerLimitEditField
        function ColourAxisLowerLimitEditFieldValueChanged(app, event)
            if app.ColourAxisUpperLimitEditField.Value < app.ColourAxisLowerLimitEditField.Value
               app.ColourAxisUpperLimitEditField.Value = app.ColourAxisLowerLimitEditField.Value + 1;
            end
            
            if app.ColourAxisLowerLimitEditField.Value > app.ColourAxisUpperLimitEditField.Value
               app.ColourAxisLowerLimitEditField.Value = app.ColourAxisUpperLimitEditField.Value - 1;
            end
            
            drawimages(app)
        end

        % Value changed function: LogarithmicMapsButton
        function LogarithmicMapsButtonValueChanged(app, event)
            value = app.LogarithmicMapsButton.Value;
            if value
                app.LogarithmicMapsButton.Text = "Log scale Relaxation Maps";
                %try and select sensible limits when log scale selected
                if  app.ColourAxisLowerLimitEditField.Value <1  %log of 0 is inf
                    app.ColourAxisLowerLimitEditField.Value = 1;
                end
                app.ColourAxisLowerLimitEditField.Value =  log(app.ColourAxisLowerLimitEditField.Value);
                app.ColourAxisUpperLimitEditField.Value =  log(app.ColourAxisUpperLimitEditField.Value);
            else
                app.LogarithmicMapsButton.Text = "Linear scale Relaxation Maps";
                app.ColourAxisLowerLimitEditField.Value =  exp(app.ColourAxisLowerLimitEditField.Value);
                app.ColourAxisUpperLimitEditField.Value =  exp(app.ColourAxisUpperLimitEditField.Value);
            end
            drawimages(app)
            
        end

        % Value changed function: DisplayingasT1Button
        function DisplayingasT1ButtonValueChanged(app, event)
            value = app.DisplayingasT1Button.Value;
            if value
                %try and select sensible limits since R1 is in s-1 and T1
                %is in ms
                app.DisplayingasT1Button.Text = "Displaying as R1";
                app.ColourAxisLowerLimitEditField.Value =  app.ColourAxisLowerLimitEditField.Value/100;
                app.ColourAxisUpperLimitEditField.Value =  app.ColourAxisUpperLimitEditField.Value/100;
            else
                app.DisplayingasT1Button.Text = "Displaying as T1";
                app.ColourAxisLowerLimitEditField.Value =  app.ColourAxisLowerLimitEditField.Value*100;
                app.ColourAxisUpperLimitEditField.Value =  app.ColourAxisUpperLimitEditField.Value*100;
            end
            
            drawimages(app)
        end

        % Button pushed function: ExportscantoworkspaceButton
        function ExportscantoworkspaceButtonPushed(app, event)
         file = app.scan_data{app.currentscan};

    try
        rawName = file.displayname;
    catch exception
        rawName = "scan";
    end


    % Convert to char/string safely
    rawName = string(rawName);

    % Make valid MATLAB variable name
    name = matlab.lang.makeValidName(rawName);

 
    existing = evalin('base','who');
    name = matlab.lang.makeUniqueStrings(name, existing);

    assignin('base', name, file);

    % Optional: tell the user what name it used
    uialert(app.UIFigure, "Exported as: " + name, "Export complete");
    
        end

        % Button pushed function: ExportscanparameterstoworkspaceButton
        function ExportscanparameterstoworkspaceButtonPushed(app, event)
            %extract all the relevant scan parameters from the currently
            %selected file and display them in the command window
            file = app.scan_data{app.currentscan};
            disp('--------------------')
            disp(['Experiment name = ',file.name])
            disp(['Sequence name = ',file.sequence])
            disp(['Matrix size = ',num2str(file.samples),' x ',num2str(file.samples)])
            if file.partialkspace == 1
                disp('Partial Fourier = True')
            else
                disp('Partial Fourier = False')
            end
            disp(['Acquisition Bandwidth = ',num2str(file.bandwidth),' KHz'])
            disp(['FOV = ',num2str(file.fov),' mm'])
            disp(['TE = ',num2str(file.TE),' ms'])
            disp(['Flip Angle = ',num2str(file.FlipAngle),' degrees'])
            disp_slice = app.SliceDropDown.Value;
            orientation = file.orientation(:,disp_slice)';
            orientation = num2str(orientation);
            switch orientation
                case '-90   0   0'
                    disp('Orientation = Coronal');
                case '0  0  0'
                    disp('Orientation = Axial');
                otherwise
                    disp(['Orientation = ',orientation]);
            end
            disp(['Slice thickness = ',num2str(file.thk),' mm'])
            disp(['Inplane resolution = = ',num2str(file.resolution_inplane),' mm'])
            disp('Evolution Fields (mT): ')
            disp(num2str(file.fieldpoints,3))
            disp('Evolution Times (ms): ')
            disp(num2str(file.timepoints,3))
            
            
            disp('--------------------')
        end

        % Button pushed function: kspaceButton
        function kspaceButtonPushed(app, event)
            h = figure;
            %get the image as it appears in the axis
            A = getimage(app.axis_kspace);
            cmap = colormap(app.axis_kspace);
            colormap(h,cmap)
            imagesc(A); axis off image
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            h = figure;
            A = getimage(app.axis_image);
            cmap = colormap(app.axis_image);
            colormap(h,cmap)
            if app.ImageTypeDropDown.Value == "Relaxometry Map"
                imshow(A,[app.ColourAxisLowerLimitEditField.Value,app.ColourAxisUpperLimitEditField.Value]);
                colormap(h,'jet')
            elseif app.IndividualNormalisationButton.Value
                imshow(A,[0 max(app.scan_data{app.currentscan}.magimage(:)*0.2)]);
            else
                imagesc(A); axis off image
            end
            
        end

        % Value changed function: IndividualNormalisationButton
        function IndividualNormalisationButtonValueChanged(app, event)
            value = app.IndividualNormalisationButton.Value;
            if value
                app.IndividualNormalisationButton.Text = "Relative Normalisation";
            else
                app.IndividualNormalisationButton.Text = "Individual Normalisation";
            end
            drawimages(app)
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            theta = 0;
    tform_rotation = affine2d([...
        -cosd(theta) sind(theta) 0;...
        -sind(theta) cosd(theta) 0; ...
        0 0 1]);

    % Horizontal flip matrix (about vertical axis)
    Sx = [-1 0 0;
           0 1 0;
           0 0 1];


    app.scan_data{app.currentscan}.geomT = Sx * app.scan_data{app.currentscan}.geomT;
            app.scan_data{app.currentscan}.originalcomplexkspace = imwarp(app.scan_data{app.currentscan}.originalcomplexkspace,tform_rotation);
            app.scan_data{app.currentscan}.complexkspace = imwarp(app.scan_data{app.currentscan}.complexkspace,tform_rotation);
            app.scan_data{app.currentscan}.compleximage = imwarp(app.scan_data{app.currentscan}.compleximage,tform_rotation);
            app.scan_data{app.currentscan}.magimage = imwarp(app.scan_data{app.currentscan}.magimage,tform_rotation);
            app.scan_data{app.currentscan}.phaseimage = imwarp( app.scan_data{app.currentscan}.phaseimage,tform_rotation);
            app.scan_data{app.currentscan}.T1Maps = imwarp(app.scan_data{app.currentscan}.T1Maps,tform_rotation);
            app.scan_data{app.currentscan}.R1Maps = imwarp(app.scan_data{app.currentscan}.R1Maps,tform_rotation);
            drawimages(app)
           
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            theta = 0;
    tform_rotation = affine2d([...
        cosd(theta) sind(theta) 0;...
        -sind(theta) -cosd(theta) 0; ...
        0 0 1]);

    % Vertical flip matrix (about horizontal axis)
    Sy = [ 1  0 0;
           0 -1 0;
           0  0 1];

    
    app.scan_data{app.currentscan}.geomT = Sy * app.scan_data{app.currentscan}.geomT;
            app.scan_data{app.currentscan}.originalcomplexkspace = imwarp(app.scan_data{app.currentscan}.originalcomplexkspace,tform_rotation);
            app.scan_data{app.currentscan}.complexkspace = imwarp(app.scan_data{app.currentscan}.complexkspace,tform_rotation);
            app.scan_data{app.currentscan}.compleximage = imwarp(app.scan_data{app.currentscan}.compleximage,tform_rotation);
            app.scan_data{app.currentscan}.magimage = imwarp(app.scan_data{app.currentscan}.magimage,tform_rotation);
            app.scan_data{app.currentscan}.phaseimage = imwarp( app.scan_data{app.currentscan}.phaseimage,tform_rotation);
            app.scan_data{app.currentscan}.T1Maps = imwarp(app.scan_data{app.currentscan}.T1Maps,tform_rotation);
            app.scan_data{app.currentscan}.R1Maps = imwarp(app.scan_data{app.currentscan}.R1Maps,tform_rotation);
            drawimages(app)
        end

        % Button pushed function: ExportallimagesButton
        function ExportallimagesButtonPushed(app, event)
            absolute_scale = app.IndividualNormalisationButton.Value;
            ImageType = app.ImageTypeDropDown.Value;
            fieldlist = app.scan_data{app.currentscan}.fieldpoints;
            timepoints =app.scan_data{app.currentscan}.timepoints;
            n_timepoints = app.scan_data{app.currentscan}.n_timepoints;
            n_fieldpoints = app.scan_data{app.currentscan}.n_fieldpoints;
            
            switch ImageType
                case "Relaxometry Map"
                    if app.DisplayingasT1Button.Value
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.R1Maps);
                        tag = 'R1';
                    else
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.T1Maps);
                        tag = 'T1';
                    end
                    
                    for field = 1:n_fieldpoints
                        imagename = char(([num2str(round(fieldlist(field))) ' mT_' tag 'map.tif']));
                        minD = app.ColourAxisLowerLimitEditField.Value;
                        maxD = app.ColourAxisUpperLimitEditField.Value*1;
                        mapped_image = ind2rgb(double(round((1+imagesforexport(:,:,1,field) - minD) * 255 ./ (maxD - minD))), jet(256)); %this creates a tif RGB image with the same colour scaling as in the GUI
                        imwrite(mapped_image, imagename);
                    end
                    
                case "Phase"
                    imagesforexport = squeeze(app.scan_data{app.currentscan}.phaseimage);
                    tag = 'phasemap';
                    for field = 1:n_fieldpoints
                        for times = 1:n_timepoints
                            imagename = char(([num2str(round(fieldlist(field))) ' mT_' num2str(round(timepoints(field,times))),' ms ' tag '.tif']));
                            imwrite(mat2gray(imagesforexport(:,:,times,field)),imagename);
                        end
                    end
                    
                case "Magnitude"
                    imagesforexport = (app.scan_data{app.currentscan}.magimage);
                   for slice = 1:app.scan_data{app.currentscan}.slices
                    for field = 1:n_fieldpoints
                        for times = 1:n_timepoints
                            imagename = char(([num2str(round(fieldlist(field))) ' mT_' num2str(round(timepoints(field,times).*100)),' ms_slice_' num2str(slice) '.tif']));
                            if absolute_scale
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field),[0 max(app.scan_data{app.currentscan}.magimage(:)*0.2)]),imagename);
                            else
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field)),imagename);
                            end
                        end
                    end
                   end
            end
            
        end

        % Button pushed function: ExportstackedimagesButton
        function ExportstackedimagesButtonPushed(app, event)
            absolute_scale = app.IndividualNormalisationButton.Value;
            ImageType = app.ImageTypeDropDown.Value;
            fieldlist = app.scan_data{app.currentscan}.fieldpoints;
            n_timepoints = app.scan_data{app.currentscan}.n_timepoints;
            n_fieldpoints = app.scan_data{app.currentscan}.n_fieldpoints;
            
            switch ImageType
                case "Relaxometry Map"
                    if app.DisplayingasT1Button.Value
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.R1Maps);
                        tag = 'R1';
                    else
                        imagesforexport = squeeze(app.scan_data{app.currentscan}.T1Maps);
                        tag = 'T1';
                    end
                    
                    for field = 1:n_fieldpoints
                        imagename = char((['Stacked ' tag 'map.tif']));
                        minD = app.ColourAxisLowerLimitEditField.Value;
                        maxD = app.ColourAxisUpperLimitEditField.Value;
                        mapped_image = ind2rgb(double(round((1+imagesforexport(:,:,field) - minD) * 255 ./ (maxD - minD))), jet(256)); %this creates a tif RGB image with the same colour scaling as in the GUI
                        imwrite(mapped_image, imagename,'WriteMode','append');
                    end
                    
                case "Phase"
                    imagesforexport = squeeze(app.scan_data{app.currentscan}.phaseimage);
                    for field = 1:n_fieldpoints
                        imagename = char(([num2str(round(fieldlist(field))) ' mT phase stack.tif']));
                        if exist(imagename, 'file')==2
                            delete(imagename);
                        end
                        for times = 1:n_timepoints
                            
                            imwrite(mat2gray(imagesforexport(:,:,times,field)),imagename,'WriteMode','append'); %writemode append adds new images to an existing one, creating a stacked image
                        end
                    end
                    
                case "Magnitude"
                    imagesforexport = (app.scan_data{app.currentscan}.magimage);
                 %%   for slice = 1:app.scan_data{app.currentscan}.slices
                    for slice = 1:1
                    for field = 1:n_fieldpoints
                        imagename = char(([num2str(round(fieldlist(field))) ' mT stack.tif']));
                        if exist(imagename, 'file')==2
                            delete(imagename);
                        end
                        for times = 1:n_timepoints
                            if absolute_scale
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field),[0 max(app.scan_data{app.currentscan}.magimage(:)*0.2)]),imagename,'WriteMode','append');
                            else
                                imwrite(mat2gray(imagesforexport(:,:,slice,times,field)),imagename,'WriteMode','append');
                            end
                        end
                    end
                    end
            end
        end

        % Callback function
        function ClusterAnalysisButtonPushed(app, event)
            try FFCAnalysis_V3(app.scan_data{app.currentscan})
            catch ME %#ok<NASGU>
                errordlg('Error launching FFC Analysis. Check that FFCAnalysis is included in the MATLAB path')
            end
        end

        % Button pushed function: RegisterImagesButton
        function RegisterImagesButtonPushed(app, event)
         
imtemp = app.scan_data{app.currentscan}.magimage;
imreg = abs(single(register_images(imtemp)));
app.scan_data{app.currentscan}.magimage = imreg;

% imreg = register_images_rotation(imreg);

%app.scan_data{app.currentscan}.originalcomplexkspace =   fft2c(imreg);


drawimages(app)
        end

        % Button pushed function: SNRButton
        function SNRButtonPushed(app, event)
            %Can be hardcoded once slice known
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field));

            %Dimensions
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            %Normalising
            targetSliceNorm = mat2gray(targetSlice);
            maskInitial = imbinarize(targetSliceNorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            se = strel('disk', 4);
            maskFilled = imclose(maskInitial,se);
            
            %Identify and count pixels in connected groups of pixels in the binary mask
            CC = bwconncomp(maskFilled);
            %Applying the numel function to every cell (group of connected pixels) in
            %the CC array
            numPixels = cellfun(@numel, CC.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixels);

            %Create a binary mask with the largest region of pixels
            maskMROI = zeros(rows, cols, 'logical');
            maskMROI(CC.PixelIdxList{idx}) = true;
    
            %Using imerode to shrink the mask to ensure none of the edge pixels are included in the signal average
            seErode = strel('disk', 6);
            maskMROIRefined = imerode(maskMROI, seErode);
            
            %Mean signal intensity
            S = mean(targetSlice(maskMROIRefined));
    
            %Number and size of noise ROI's
            noiseRegions = 4;
            noiseStd = zeros(noiseRegions, 1);
            noiseBoxSize = round(rows * 0.05);
            buffer = 8;
    
            %Creating a table of starting coordinates to place the noise regions, with an 8 pixel buffer from the edges
            cornerCoords = [
                buffer, buffer;
                cols - noiseBoxSize - buffer, buffer;
                cols - noiseBoxSize - buffer, rows - noiseBoxSize - buffer;
                buffer, rows - noiseBoxSize - buffer
            ];

            figure('Name', 'SNR Analysis', 'NumberTitle', 'off');
            overlayImg = labeloverlay(targetSliceNorm, maskMROIRefined, 'Transparency', 0.7, 'Colormap', 'hot');
            imshow(overlayImg);
            title(['SNR Analysis - Slice ', num2str(disp_slice)], 'FontSize', 14);
            axis square
            hold on;
    
            for i = 1:noiseRegions
                xStart = cornerCoords(i, 1);
                yStart = cornerCoords(i, 2);
        
                maskNoise = false(rows, cols);
                maskNoise(yStart : yStart + noiseBoxSize, xStart : xStart + noiseBoxSize) = true;
                noiseStd(i) = std(targetSlice(maskNoise));
        
                rectangle('Position', [xStart, yStart, noiseBoxSize, noiseBoxSize], 'EdgeColor', 'g', 'LineWidth', 2);
            end
            hold off;

            %Calculation of the mean noise which is corrected by dividing by 0.66 which accounts for the Rayleigh distribution of the noise in the magnitude image.
            noiseAvg = mean(noiseStd);
            noiseCorrected = noiseAvg / 0.66; 
            %SNR calculation
            snr = S / noiseCorrected;

            fprintf('--- Signal to Noise Ratio ---\n');
            fprintf('Slice %d SNR: %.3f | Mean Signal: %.4f\n | Mean Noise: %.4f\n', disp_slice, snr, S, noiseAvg);
            
        end

        % Value changed function: MultichannelDropDown
        function MultichannelDropDownValueChanged2(app, event)
          %updateimages(app)
        end

        function noiseWhiteningValueChanged(app, source)
            if isempty(app.scan_data) || isempty(app.currentscan)
                source.Value = false;
                return;
            end

            if app.scan_data{app.currentscan}.n_receivers <= 1
                source.Value = false;
                source.Enable = 'off';
                app.scan_data{app.currentscan}.noise_whitening = false;
                return;
            end

            app.scan_data{app.currentscan}.noise_whitening = logical(source.Value);
            updateimages(app);
        end

        % Button pushed function: RebuildImagesButton
        function RebuildImagesButtonPushed(app, event)
           updateimages(app) 
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

    % Ignore keypresses when user is typing into controls
    obj = app.UIFigure.CurrentObject;
    if ~isempty(obj) && isprop(obj,'Type')
        if any(strcmp(obj.Type, {'uieditfield','uitextarea','uidropdown','uispinner'}))
            return;
        end
    end

    switch event.Key

        % -------- Slice navigation --------
        case 'uparrow'
            v = app.SliceDropDown.Value;
            if v > min(app.SliceDropDown.ItemsData)
                app.SliceDropDown.Value = v - 1;
                drawimages(app);
            end

        case 'downarrow'
            v = app.SliceDropDown.Value;
            if v < max(app.SliceDropDown.ItemsData)
                app.SliceDropDown.Value = v + 1;
                drawimages(app);
            end

        % -------- Evolution FIELD navigation --------
        case 'leftarrow'
            v = app.EvolutionTimeDropDown.Value;
            if v > min(app.EvolutionTimeDropDown.ItemsData)
                app.EvolutionTimeDropDown.Value = v - 1;
               drawimages(app);
            end

        case 'rightarrow'
            v = app.EvolutionTimeDropDown.Value;
            if v < max(app.EvolutionTimeDropDown.ItemsData)
                app.EvolutionTimeDropDown.Value = v + 1;
                drawimages(app);
            end
    end


        end

        % Button down function: axis_image
        function axis_imageButtonDown(app, event)
            figure(app.UIFigure);   % give UIFigure focus so arrows work
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            function UIFigureWindowKeyPress(app, event)

    switch event.Key
        case 'uparrow'
            v = app.SliceDropDown.Value;
            if v > 1
                app.SliceDropDown.Value = v - 1;
                drawimages(app);
            end

        case 'downarrow'
            v = app.SliceDropDown.Value;
            vmax = max(app.SliceDropDown.ItemsData);
            if v < vmax
                app.SliceDropDown.Value = v + 1;
                drawimages(app);
            end

        case 'leftarrow'
            v = app.EvolutionFieldDropDown.Value;
            if v > 1
                app.EvolutionFieldDropDown.Value = v - 1;
                EvolutionFieldDropDownValueChanged(app, []);
            end

        case 'rightarrow'
            v = app.EvolutionFieldDropDown.Value;
            vmax = max(app.EvolutionFieldDropDown.ItemsData);
            if v < vmax
                app.EvolutionFieldDropDown.Value = v + 1;
                EvolutionFieldDropDownValueChanged(app, []);
            end
    end
end

            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
        
    % Only respond if click is on the image axes (or an object inside it)
    obj = app.UIFigure.CurrentObject;

    onImageAxes = isequal(obj, app.axis_image);
    if ~onImageAxes
        try
            onImageAxes = isprop(obj,'Parent') && isequal(obj.Parent, app.axis_image);
        catch
        end
    end
    if ~onImageAxes
        return;
    end

    % ---- DOUBLE CLICK = AUTO WINDOW ----
    if strcmp(app.UIFigure.SelectionType, 'open')

        disp_slice = app.SliceDropDown.Value;
        disp_time  = app.EvolutionTimeDropDown.Value;
        disp_field = app.EvolutionFieldDropDown.Value;

        img = app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field);

        autoWindowFromImage(app, img);   % your helper
        drawimages(app);
        return;
    end

    % ---- SINGLE CLICK = START DRAG WINDOWING ----
    % Left button only if available
    if isprop(event,'Button') && event.Button ~= 1
        return;
    end

    s.isDragging = true;
    s.startPoint = app.UIFigure.CurrentPoint;
    s.startWW = app.WindowWidthSlider.Value;     % Window Width
    s.startWL = app.WindowLevelSlider.Value;   % Window Level
    app.UIFigure.UserData = s;

    figure(app.UIFigure);
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
           s = app.UIFigure.UserData;
    if isstruct(s)
        s.isDragging = false;
        app.UIFigure.UserData = s;
    end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
         s = app.UIFigure.UserData;
    if ~isstruct(s) || ~isfield(s,'isDragging') || ~s.isDragging
        return;
    end

    cp = app.UIFigure.CurrentPoint;           % [x y] in figure pixels
    dx = cp(1) - s.startPoint(1);             % horizontal drag
    dy = cp(2) - s.startPoint(2);             % vertical drag

    % ---- PACS-like sensitivity ----
    k = 1/300;   % increase (e.g. 1/200) if you want faster response

    % Window Width: multiplicative (exponential) so it never goes <= 0
    newWW = s.startWW * exp(dx * k);

    % Window Level: additive, scaled by starting WW (so it feels consistent)
    newWL = s.startWL + dy * (s.startWW * k);

    % ---- Clamp to slider limits ----
    newWW = max(app.WindowWidthSlider.Limits(1),   min(app.WindowWidthSlider.Limits(2),   newWW));
    newWL = max(app.WindowLevelSlider.Limits(1), min(app.WindowLevelSlider.Limits(2), newWL));

    % ---- Update sliders (WW = ContrastSlider, WL = BrightnessSlider) ----
    app.WindowWidthSlider.Value   = newWW;
    app.WindowLevelSlider.Value = newWL;

    drawimages(app);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
             % No data loaded
    if isempty(app.scan_data) || isempty(app.currentscan)
        return;
    end

    % Wheel direction (+ve = scroll down)
    step = event.VerticalScrollCount;

    % Keyboard modifiers
    mods = app.UIFigure.CurrentModifier;
    shiftHeld = any(strcmp(mods, 'shift'));
    ctrlHeld  = any(strcmp(mods, 'control'));

    % Decide what we are scrolling
    if ctrlHeld
        dd = app.EvolutionFieldDropDown;   % Ctrl + wheel → field
    elseif shiftHeld
        dd = app.EvolutionTimeDropDown;    % Shift + wheel → time
    else
        dd = app.SliceDropDown;            % Wheel → slice
    end

    if isempty(dd.ItemsData)
        return;
    end

    % Current value
    v = dd.Value;
    vmin = min(dd.ItemsData);
    vmax = max(dd.ItemsData);

    % Scroll (invert sign here if direction feels wrong)
    vNew = v + step;

    % Clamp
    vNew = max(vmin, min(vmax, vNew));

    if vNew ~= v
        dd.Value = vNew;

        % Field changes must update time list
        if dd == app.EvolutionFieldDropDown
            EvolutionFieldDropDownValueChanged(app, []);
        else
            drawimages(app);
        end
    end
            
        end

        % Value changed function: CombinationDropDown
        function CombinationDropDownValueChanged(app, event)
            value = app.CombinationDropDown.Value;
            
        end

        % Button pushed function: GeometricAccButton
        function GeometricAccButtonPushed(app, event)
      % ---------------------- Your existing setup ----------------------
disp_slice = app.SliceDropDown.Value;
disp_field = app.EvolutionFieldDropDown.Value;
disp_time  = app.EvolutionTimeDropDown.Value;

targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field));

[rows, cols] = size(targetSlice);

fov = app.scan_data{app.currentscan}.fov;
pixelSize = fov/rows;

targetSliceNorm = mat2gray(targetSlice);

% ---------------------- Phantom mask (keep your logic, but add 2 safety lines) ----------------------
maskInitial = imbinarize(targetSliceNorm);
maskInitial = imfill(maskInitial,'holes');
maskInitial = bwareaopen(maskInitial, round(0.001*rows*cols));

se = strel('disk', 3);
maskFilled = imclose(maskInitial, se);

CC = bwconncomp(maskFilled);
numPixels = cellfun(@numel, CC.PixelIdxList);
[~, idx] = max(numPixels);

maskMROI = false(size(maskFilled));
maskMROI(CC.PixelIdxList{idx}) = true;

% Need stats early for phantom centroid fallback
phantomStats = regionprops(maskMROI,'Centroid');
cx0 = phantomStats.Centroid(1);
cy0 = phantomStats.Centroid(2);

% ---------------------- GRID CENTRE (projection method) ----------------------
I = targetSliceNorm;

maskInner = imerode(maskMROI, strel('disk', 12));   % tweak 8–20
if ~any(maskInner(:)), maskInner = maskMROI; end

Iroi = I; 
Iroi(~maskInner) = 0;

Ibh = imbothat(Iroi, strel('disk', 7));            % tweak 3–9
Ibh = mat2gray(Ibh);
Ibh = imgaussfilt(Ibh, 0.8);                       % tweak 0–1.5

kv = [1 -2 1; 1 -2 1; 1 -2 1];
kh = kv';

Ev = abs(imfilter(Ibh, kv, 'replicate'));
Eh = abs(imfilter(Ibh, kh, 'replicate'));
Ev(~maskInner) = 0;
Eh(~maskInner) = 0;

profX = smoothdata(sum(Ev,1), 'gaussian', 11);
profY = smoothdata(sum(Eh,2), 'gaussian', 11);

colMask = any(maskInner,1);
rowMask = any(maskInner,2);

[pksX, locX] = findpeaks(profX .* colMask, 'MinPeakProminence', 0.02*max(profX));
[pksY, locY] = findpeaks(profY .* rowMask, 'MinPeakProminence', 0.02*max(profY));

if isempty(locX) || isempty(locY)
    warning('Grid projections failed; falling back to phantom centroid.');
    x_center = cx0;
    y_center = cy0;
else
    bestScore = -Inf;
    bestXY = [cx0, cy0];

    distPenalty = 1; % <<<< FIXED (your "1" made it overly aggressive)

    for i = 1:numel(locX)
        for j = 1:numel(locY)
            x = locX(i);
            y = locY(j);

            xi = max(1, min(cols, round(x)));
            yi = max(1, min(rows, round(y)));

            if ~maskInner(yi, xi)
                continue
            end

            d2 = (x - cx0)^2 + (y - cy0)^2;
            score = (pksX(i) + pksY(j)) - d2/distPenalty;

            if score > bestScore
                bestScore = score;
                bestXY = [x, y];
            end
        end
    end

    x_center = bestXY(1);
    y_center = bestXY(2);
end

gridCenter = [x_center, y_center];

% ---------------------- GRID ANGLE (constrained Hough + intersection suppression) ----------------------
lineLen = 15;   % tweak 10–25 depending on resolution
horizLines = imopen(Ibh, strel('line', lineLen, 0));
vertLines  = imopen(Ibh, strel('line', lineLen, 90));
Igrid = max(horizLines, vertLines);
Igrid(~maskInner) = 0;

edges = edge(Igrid, 'canny');
edges(~maskInner) = 0;

thetaRange = [-10:0.5:10, 80:0.5:89, -90:0.5:-80];
[H,T,R] = hough(edges, 'Theta', thetaRange);

P = houghpeaks(H, 25, 'Threshold', 0.30*max(H(:)));
hlines = houghlines(edges, T, R, P, 'FillGap', 25, 'MinLength', 60);

angles = [];
weights = [];

for k = 1:numel(hlines)
    dx = hlines(k).point2(1) - hlines(k).point1(1);
    dy = hlines(k).point2(2) - hlines(k).point1(2);
    Lseg = hypot(dx, dy);
    if Lseg < 1, continue; end

    ang = atan2d(dy, dx);     % line direction
    ang = mod(ang, 90);       % fold to [0,90)

    angles(end+1)  = ang; %#ok<AGROW>
    weights(end+1) = Lseg; %#ok<AGROW>
end

if isempty(angles)
    gridAngleDeg = 0;
else
    S = sum(weights .* sind(2*angles));
    C = sum(weights .* cosd(2*angles));
    gridAngleDeg = 0.5 * atan2d(S, C);
end

% ---------------------- DIAMETER MEASUREMENT ALONG 45°-OFFSET AXES ----------------------
% Missing fluid shortens one of the grid-aligned axes when clipped to mask.
% Use axes rotated by +45° (and +135°) relative to the grid axes.
phiMeas = gridAngleDeg + 45;

L = 0.90 * min(rows, cols);     % long enough to span phantom
n = 4000;                       % sampling density

% Build two orthogonal measurement lines (unclipped)
xA = [x_center - L*cosd(phiMeas),      x_center + L*cosd(phiMeas)];
yA = [y_center - L*sind(phiMeas),      y_center + L*sind(phiMeas)];
xB = [x_center - L*cosd(phiMeas+90),   x_center + L*cosd(phiMeas+90)];
yB = [y_center - L*sind(phiMeas+90),   y_center + L*sind(phiMeas+90)];

% Clip A to maskMROI by sampling along the line
xs = linspace(xA(1), xA(2), n);
ys = linspace(yA(1), yA(2), n);
xi = max(1, min(cols, round(xs)));
yi = max(1, min(rows, round(ys)));
inside = maskMROI(sub2ind([rows, cols], yi, xi));
idx = find(inside);
if ~isempty(idx)
    i1 = idx(1); i2 = idx(end);
    xAc = [xs(i1), xs(i2)];
    yAc = [ys(i1), ys(i2)];
    diamA_mm = hypot(xAc(2)-xAc(1), yAc(2)-yAc(1)) * pixelSize;
else
    xAc = xA; yAc = yA;
    diamA_mm = NaN;
end

% Clip B to maskMROI
xs = linspace(xB(1), xB(2), n);
ys = linspace(yB(1), yB(2), n);
xi = max(1, min(cols, round(xs)));
yi = max(1, min(rows, round(ys)));
inside = maskMROI(sub2ind([rows, cols], yi, xi));
idx = find(inside);
if ~isempty(idx)
    i1 = idx(1); i2 = idx(end);
    xBc = [xs(i1), xs(i2)];
    yBc = [ys(i1), ys(i2)];
    diamB_mm = hypot(xBc(2)-xBc(1), yBc(2)-yBc(1)) * pixelSize;
else
    xBc = xB; yBc = yB;
    diamB_mm = NaN;
end

% Use the measurement that is LESS affected by the missing-fluid notch.
% Typically the "too short" one will be the minimum, so use the MAX.
calcDiameter = max(diamA_mm, diamB_mm);

% ---------------------- Plot + draw 45°-offset measurement axes ----------------------
acrDiameter = 190;

figure('Name', 'Geometric Accuracy Analysis', 'NumberTitle', 'off');
imshow(labeloverlay(targetSliceNorm, maskMROI, 'Transparency', 0.8, 'Colormap', 'spring'));
axis square;

title(sprintf('Measured Diameter (45° axes): %.2f mm  (A=%.2f, B=%.2f)  Angle=%.1f°', ...
    calcDiameter, diamA_mm, diamB_mm, phiMeas));

hold on
plot(x_center, y_center, 'r+', 'MarkerSize', 14, 'LineWidth', 2);

line(xAc, yAc, 'Color', 'cyan', 'LineWidth', 2, 'DisplayName', sprintf('Meas axis %.1f°', mod(phiMeas,180)));
line(xBc, yBc, 'Color', 'cyan', 'LineWidth', 2, 'DisplayName', sprintf('Meas axis %.1f°', mod(phiMeas+90,180)));

hold off

% ---------------------- PASS/MARGINAL/FAIL (NOW BASED ON 45° DIAMETER) ----------------------
err = abs(calcDiameter - acrDiameter);
if err <= 2
    resultStr = 'PASS';
elseif err <= 3
    resultStr = 'MARGINAL';
else
    resultStr = 'FAIL';
end

fprintf('--- Geometric Accuracy (45° axes) ---\n');
fprintf('Grid centre (px): [%.1f, %.1f]\n', x_center, y_center);
fprintf('Grid angle (deg): %.2f\n', gridAngleDeg);
fprintf('Measured axes angle (deg): %.2f\n', phiMeas);
fprintf('Diameter A (mm): %.2f\nDiameter B (mm): %.2f\n', diamA_mm, diamB_mm);
fprintf('Selected Diameter (mm): %.2f\nACR Diameter (mm): %.2f\nError (mm): %.2f\nResult: %s\n', ...
    calcDiameter, acrDiameter, err, resultStr);
        end

        % Button pushed function: UniformityButton
        function UniformityButtonPushed(app, event)
             %This can be hardcoded for the specific slice once confirmed
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field));

            %Extracts the dimesnions fro  the scan data
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            fov = app.scan_data{app.currentscan}.fov;
            %Calculating the size of one pixel in mm
            pixelSize = fov/rows;

            %Normalising the pixel values within the slice
            targetSliceNorm = mat2gray(targetSlice);
            %Thresholding using an adaptive sensitivity
            maskInitial = imbinarize(targetSliceNorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            se = strel('disk', 3);
            %Making a smoothed mask of the signal region of the phantom
            maskFilled = imclose(maskInitial, se);

            %Identify and count pixels in connected groups of pixels in the binary mask
            CC = bwconncomp(maskFilled);
            %Applying the numel function to every cell (group of connected pixels) in the CC array
            numPixels = cellfun(@numel, CC.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixels);

            %Create a binary mask with the largest region of pixels
            maskMROI = zeros(rows, cols, 'logical');
            maskMROI(CC.PixelIdxList{idx}) = true;

            %Shrink mask by 20mm from the phantom edge
            shrinkPixels = round(20 / pixelSize);
            maskMROIRefined = imerode(maskMROI, strel('disk', shrinkPixels));
           
            %Create a circular averaging filter to simulate a 1cm^2 ROI (ACR guidance)
            %Get the highest region rather than single pixel to avoid noise outliers
            roiSize = round(sqrt(100/pi) / pixelSize);
            %Making a smoothing filter, disk shaped mask with the specified radius
            h = fspecial('disk', roiSize);
            %imfilter moves the mask over every pixel and replaces the value with the average of its neighbours in the given radius
            %'replicate' assumes the edge pixels of the image are the same as the overhang
            smoothedSlice = imfilter(targetSlice, h, 'replicate');

            %Find high and low mean signal values within the smoothed signal region
            phantomValues = smoothedSlice(maskMROIRefined);
            high = max(phantomValues);
            low = min(phantomValues);

            %Locate the centers of these regions for plotting, by finding pixel equal to the high or low that falls in the refined mask region
            [maxRow, maxCol] = find(smoothedSlice == high & maskMROIRefined, 1);
            [minRow, minCol] = find(smoothedSlice == low & maskMROIRefined, 1);

            %Percent Integral Uniformity (ACR guidance)
            piu = 100 * (1 - ((high - low)/(high + low)));

            figure('Name', 'Image Uniformity Analysis', 'NumberTitle', 'off');
            %Displaying the original slice with the refined mask overlay
            imshow(labeloverlay(targetSliceNorm, maskMROI, 'Transparency', 0.8, 'Colormap', 'summer'));
            title(sprintf('PIU: %.2f%% (High: %.2f, Low: %.2f)', piu, high, low));
            axis square;
            hold on;
            %creating a circular element which had the radius required, to
            %display onto the figure
            viscircles([maxCol, maxRow], roiSize, 'EdgeColor', 'r', 'LineWidth', 2);
            viscircles([minCol, minRow], roiSize, 'EdgeColor', 'b', 'LineWidth', 2);
            %Non numerical 'placeholder' for the legend, doesn't work with
            %viscircles
            hMax = plot(NaN, NaN, 'or', 'LineWidth', 2);
            hMin = plot(NaN, NaN, 'ob', 'LineWidth', 2); 
            hMask = plot(NaN, NaN, 's', 'MarkerEdgeColor', 'none', 'MarkerFaceColor', [0.8 1 0.8]);
            legend([hMask, hMax, hMin], {'Refined ROI', 'Max Signal ROI', 'Min Signal ROI'}, 'Location', 'southoutside', 'Orientation', 'horizontal');
            hold off;

            %For images acquired using the large phantom on MRI systems with field strengths less than 3 Tesla, PIU should be greater than or equal to 87.5% and will fail if less than 85%.
            fprintf('--- Image Uniformity ---\n');
            fprintf('PIU: %.2f%%\nMax ROI Mean: %.2f\nMin ROI Mean: %.2f\n', piu, high, low);
            
            if piu >= 87.5
                fprintf('Result: PASS\n');
            elseif piu >= 85
                fprintf('Result: MARGINAL (Pass >= 85, but below 87.5 preferred)\n');
            else
                fprintf('Result: FAIL (below 85)\n');
            end
        end

        % Button pushed function: THKButton
        function THKButtonPushed(app, event)
             %This can be hardcoded for the specific slice once confirmed
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            targetSlice = abs(app.scan_data{app.currentscan}.magimage(:,:,disp_slice,disp_time,disp_field));

            %Extracts the dimesnions fro  the scan data
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            fov = app.scan_data{app.currentscan}.fov;
            %Calculating the size of one pixel in mm
            pixelSize = fov/rows;

            %Normalising the pixel values within the slice
            targetSliceNorm = mat2gray(targetSlice);

            figure('Name', 'Slice Thickness - Manual')
            imagesc(targetSliceNorm)
            title('Slice Thickness - Manual',  'FontSize', 20)
            axis square
            axis off
            colormap gray
            annotation('textbox', [0.28, 0.0, 0.1, 0.1], 'String', "Draw 2 lines, measuring the lengths of the signal bars")

            length1 = drawline('Color', 'b');
            length2 = drawline('Color', 'g');

            %The position of the roi line is given by a 2x2 matrix where each row
            %is one of the end points of the line
            pos1 = length1.Position;
            pos2 = length2.Position;

            %Use the geometric distance formula to calculate the length in pixels
            pixelLength1 = sqrt(diff(pos1(:,1))^2 + diff(pos1(:,2))^2);
            pixelLength2 = sqrt(diff(pos2(:,1))^2 + diff(pos2(:,2))^2);

            top = pixelSize*pixelLength1;
            bottom = pixelSize*pixelLength2;

            %Calculate the slice thickness
            sliceThickness = 0.2 * ( (top * bottom) / (top + bottom) );
            trueThickness = 5.0; %mm

            %Print the result
            fprintf('Slice Thickness: %.2f mm\n', sliceThickness);

            %ACR guidance states that all measured lengths should be 5.0 mm +/- 0.7 mm.
            %It will fail if it is +/- 1.0 mm
            if abs(sliceThickness - trueThickness) <= 0.7
                fprintf('Result: PASS\n');
            elseif abs(sliceThickness - trueThickness) >= 0.7 && abs(sliceThickness - trueThickness) <= 1.0
                fprintf('Result: Over 0.7 mm difference, but under 1.0 mm failure point\n');
            else
                fprintf('Result: FAIL (Outwith 1 mm tolerance)\n');
            end
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            %DEFINITIONS
            %Current GUI image
            disp_slice = app.SliceDropDown.Value;
            disp_field = app.EvolutionFieldDropDown.Value;
            disp_time = app.EvolutionTimeDropDown.Value;

            %Hardcoded slices
            sliceSNR = 41;
            sliceGEO = 41;
            sliceUNI = 41;
            sliceTHI = 70;

            targetSliceSNR = abs(app.scan_data{app.currentscan}.magimage(:,:,sliceSNR,disp_time,disp_field));
            targetSliceGEO = abs(app.scan_data{app.currentscan}.magimage(:,:,sliceGEO,disp_time,disp_field));
            targetSliceUNI = abs(app.scan_data{app.currentscan}.magimage(:,:,sliceUNI,disp_time,disp_field));
            targetSliceTHI = abs(app.scan_data{app.currentscan}.magimage(:,:,sliceTHI,disp_time,disp_field));

            targetSliceSNRNorm = mat2gray(targetSliceSNR);
            targetSliceGEONorm = mat2gray(targetSliceGEO);
            targetSliceUNINorm = mat2gray(targetSliceUNI);
            targetSliceTHINorm = mat2gray(targetSliceTHI);

            %Dimensions of the scan data
            rows = app.scan_data{app.currentscan}.views;
            cols = app.scan_data{app.currentscan}.samples;

            fov = app.scan_data{app.currentscan}.fov;

            pixelSize = fov/rows;

            acrDiameter = 190;

%PRE FIGURE CALCULATIONS
            maskInitialSNR = imbinarize(targetSliceSNRNorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            seSNR = strel('disk', 4);
            maskFilledSNR = imclose(maskInitialSNR,seSNR);
            
            %Identify and count pixels in connected groups of pixels in the binary mask
            CCSNR = bwconncomp(maskFilledSNR);
            %Applying the numel function to every cell (group of connected pixels) in
            %the CC array
            numPixelsSNR = cellfun(@numel, CCSNR.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixelsSNR);

            %Create a binary mask with the largest region of pixels
            maskMROISNR = zeros(rows, cols, 'logical');
            maskMROISNR(CCSNR.PixelIdxList{idx}) = true;
    
            %Using imerode to shrink the mask to ensure none of the edge pixels are included in the signal average
            seErodeSNR = strel('disk', 6);
            maskMROIRefinedSNR = imerode(maskMROISNR, seErodeSNR);
            
            %Mean signal intensity
            S = mean(targetSliceSNR(maskMROIRefinedSNR));
    
            %Number and size of noise ROI's
            noiseRegions = 4;
            noiseStd = zeros(noiseRegions, 1);
            noiseBoxSize = round(rows * 0.05);
            buffer = 8;
    
            %Creating a table of starting coordinates to place the noise regions, with an 8 pixel buffer from the edges
            cornerCoords = [
                buffer, buffer;
                cols - noiseBoxSize - buffer, buffer;
                cols - noiseBoxSize - buffer, rows - noiseBoxSize - buffer;
                buffer, rows - noiseBoxSize - buffer
            ];

            %Applying a more sensitive threshold
            maskInitialGEO = imbinarize(targetSliceGEONorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            seGEO = strel('disk', 3);
            maskFilledGEO = imclose(maskInitialGEO, seGEO);

            %Identify and count pixels in connected groups of pixels in the binary mask
            CCGEO = bwconncomp(maskFilledGEO);
            %Applying the numel function to every cell (group of connected pixels) in the CC array
            numPixelsGEO = cellfun(@numel, CCGEO.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixelsGEO);

            %Create a binary mask with the largest region of pixels
            maskMROIGEO = zeros(rows, cols, 'logical');
            maskMROIGEO(CCGEO.PixelIdxList{idx}) = true;

            stats = regionprops(maskMROIGEO, 'EquivDiameter', 'MajorAxisLength', 'MinorAxisLength', 'Orientation', 'Centroid');
            %The diameter if the region was a perfect circle
            calcDiameter = pixelSize * stats.EquivDiameter;
            %Max and min diameters to help identify any distortion
            maxDiam = stats.MajorAxisLength * pixelSize;
            minDiam = stats.MinorAxisLength * pixelSize;
            distortion = stats.MajorAxisLength / stats.MinorAxisLength;

            %Thresholding using an adaptive sensitivity
            maskInitialUNI = imbinarize(targetSliceUNINorm); %Add this line of code if the mask isnt correct , "adaptive", Sensitivity=0.7);
            seUNI = strel('disk', 3);
            %Making a smoothed mask of the signal region of the phantom
            maskFilledUNI = imclose(maskInitialUNI, seUNI);

            %Identify and count pixels in connected groups of pixels in the binary mask
            CCUNI = bwconncomp(maskFilledUNI);
            %Applying the numel function to every cell (group of connected pixels) in the CC array
            numPixelsUNI = cellfun(@numel, CCUNI.PixelIdxList);
            %Finding the largest region of pixels; the phantom
            [~, idx] = max(numPixelsUNI);

            %Create a binary mask with the largest region of pixels
            maskMROIUNI = zeros(rows, cols, 'logical');
            maskMROIUNI(CCUNI.PixelIdxList{idx}) = true;

            %Shrink mask by 20mm from the phantom edge
            shrinkPixelsUNI = round(20 / pixelSize);
            maskMROIRefinedUNI = imerode(maskMROIUNI, strel('disk', shrinkPixelsUNI));
           
            %Create a circular averaging filter to simulate a 1cm^2 ROI (ACR guidance)
            %Get the highest region rather than single pixel to avoid noise outliers
            roiSize = round(sqrt(100/pi) / pixelSize);
            %Making a smoothing filter, disk shaped mask with the specified radius
            h = fspecial('disk', roiSize);
            %imfilter moves the mask over every pixel and replaces the value with the average of its neighbours in the given radius
            %'replicate' assumes the edge pixels of the image are the same as the overhang
            smoothedSlice = imfilter(targetSliceUNI, h, 'replicate');

            %Find high and low mean signal values within the smoothed signal region
            phantomValues = smoothedSlice(maskMROIRefinedUNI);
            high = max(phantomValues);
            low = min(phantomValues);

            %Locate the centers of these regions for plotting, by finding pixel equal to the high or low that falls in the refined mask region
            [maxRow, maxCol] = find(smoothedSlice == high & maskMROIRefinedUNI, 1);
            [minRow, minCol] = find(smoothedSlice == low & maskMROIRefinedUNI, 1);

%FIGURES
            name = sprintf('QA Test (%s)', datetime("now"));
            figure("Name", name)
            t = tiledlayout(2,2, "TileSpacing", "tight");
            axis square
            axis off
            colormap gray
            title(t,'Daily QA Test')

            %SNR
            nexttile
            imshow(labeloverlay(targetSliceSNRNorm, maskMROIRefinedSNR, 'Transparency', 0.7, 'Colormap', 'hot'));
            for i = 1:noiseRegions
                xStart = cornerCoords(i, 1);
                yStart = cornerCoords(i, 2);

                maskNoise = false(rows, cols);
                maskNoise(yStart : yStart + noiseBoxSize, xStart : xStart + noiseBoxSize) = true;
                noiseStd(i) = std(targetSliceSNR(maskNoise));

                rectangle('Position', [xStart, yStart, noiseBoxSize, noiseBoxSize], 'EdgeColor', 'g', 'LineWidth', 2);
            end
            title('SNR')

            %GEOMETRIC ACCURACY
            nexttile
            imshow(labeloverlay(targetSliceGEONorm, maskMROIGEO, 'Transparency', 0.8, 'Colormap', 'spring'));
            hold on;
            phi = -stats.Orientation;
            x_center = stats.Centroid(1);
            y_center = stats.Centroid(2);
            
            x_line = [x_center - (stats.MajorAxisLength/2) * cosd(phi), x_center + (stats.MajorAxisLength/2) * cosd(phi)];
            y_line = [y_center - (stats.MajorAxisLength/2) * sind(phi), y_center + (stats.MajorAxisLength/2) * sind(phi)];
            
            plot(stats.Centroid(1), stats.Centroid(2), 'r+', 'MarkerSize', 10, 'LineWidth', 2);
            line(x_line, y_line, 'Color', 'cyan', 'LineWidth', 2, 'DisplayName', 'Major Axis');
            hold off;
            title('Geometric Accuracy')

            %IMAGE UNIFORMITY
            nexttile
            imshow(labeloverlay(targetSliceUNINorm, maskMROIUNI, 'Transparency', 0.8, 'Colormap', 'summer'));
            hold on;
            %creating a circular element which had the radius required, to display onto the figure
            viscircles([maxCol, maxRow], roiSize, 'EdgeColor', 'r', 'LineWidth', 2);
            viscircles([minCol, minRow], roiSize, 'EdgeColor', 'b', 'LineWidth', 2);
            %Non numerical 'placeholder' for the legend, doesn't work with viscircles
            hMax = plot(NaN, NaN, 'or', 'LineWidth', 2);
            hMin = plot(NaN, NaN, 'ob', 'LineWidth', 2); 
            hMask = plot(NaN, NaN, 's', 'MarkerEdgeColor', 'none', 'MarkerFaceColor', [0.8 1 0.8]);
            legend([hMask, hMax, hMin], {'Refined ROI', 'Max Signal ROI', 'Min Signal ROI'}, 'Location', 'southoutside', 'Orientation', 'horizontal');
            hold off;
            title('Image Uniformity')

            %SLICE THICKNESS ACCURACY
            nexttile
            imshow(targetSliceTHINorm)
            title('Slice Thickness Accuracy')
            length1 = drawline('Color', 'b');
            length2 = drawline('Color', 'g');
            

%POST FIGURE CALCULATIONS
            %Calculation of the mean noise which is corrected by dividing by 0.66 which accounts for the Rayleigh distribution of the noise in the magnitude image.
            noiseAvg = mean(noiseStd);
            noiseCorrected = noiseAvg / 0.66; 
            %SNR calculation
            snr = S / noiseCorrected;

            %Calculate Percent Integral Uniformity
            piu = 100 * (1 - ((high - low)/(high + low)));

            %The position of the roi line is given by a 2x2 matrix where each row
            %is one of the end points of the line
            pos1 = length1.Position;
            pos2 = length2.Position;

            %Use the geometric distance formula to calculate the length in pixels
            pixelLength1 = sqrt(diff(pos1(:,1))^2 + diff(pos1(:,2))^2);
            pixelLength2 = sqrt(diff(pos2(:,1))^2 + diff(pos2(:,2))^2);

            top = pixelSize*pixelLength1;
            bottom = pixelSize*pixelLength2;

            %Calculate the Slice Thickness
            sliceThickness = 0.2 * ( (top * bottom) / (top + bottom) );
            trueThickness = 5.0; %mm

%OUTPUTS
            fprintf('--- Signal to Noise Ratio ---\n');
            fprintf('SNR: %.3f | Mean Signal: %.4f\n | Mean Noise: %.4f\n', snr, S, noiseAvg);
            resSNR = snr;

            %ACR guidance states that all measured lengths should be within 2 mm of their true values and will fail if the length differs by more than 3 mm
            fprintf('--- Geometric Accuracy ---\n');
            fprintf('Equivalent Diameter: %.2f mm\nMajor Axis: %.2f mm\nMinor Axis: %.2f mm\nDistortion: %.3f\n', calcDiameter, maxDiam, minDiam, distortion);
            if abs(calcDiameter - acrDiameter) <= 2
                resGEO = "pass";
                fprintf('Result: PASS\n');
            elseif abs(calcDiameter - acrDiameter) <= 3
                resGEO = "marginal";
                fprintf('Result: MARGINAL\n');
            else
                resGEO = "fail";
                fprintf('Result: FAIL (over 3 mm)\n');
            end

            %For images acquired using the large phantom on MRI systems with field strengths less than 3 Tesla, PIU should be greater than or equal to 87.5% and will fail if less than 85%.
            fprintf('--- Image Uniformity ---\n');
            fprintf('PIU: %.2f%%\nMax ROI Mean: %.2f\nMin ROI Mean: %.2f\n', piu, high, low);
            
            if piu >= 87.5
                resUNI = "pass";
                fprintf('Result: PASS\n');
            elseif piu >= 85
                resUNI = "marginal";
                fprintf('Result: MARGINAL (Pass >= 85, but below 87.5 preferred)\n');
            else
                resUNI = "fail";
                fprintf('Result: FAIL (below 85)\n');
            end

            %ACR guidance states that all measured thicknesses should be 5.0 mm +/- 0.7 mm.
            %It will fail if it is +/- 1.0 mm
            fprintf('--- Slice Thickness Accuracy ---\n');
            fprintf('Slice Thickness: %.2f mm\n', sliceThickness);
            if abs(sliceThickness - trueThickness) <= 0.7
                resTHI = "pass";
                fprintf('Result: PASS\n');
            elseif abs(sliceThickness - trueThickness) >= 0.7 && abs(sliceThickness - trueThickness) <= 1.0
                resTHI = "marginal";
                fprintf('Result: Over 0.7 mm difference, but under 1.0 mm failure point\n');
            else
                resTHI = "fail";
                fprintf('Result: FAIL (Outwith 1 mm tolerance)\n');
            end

            results = [resSNR resGEO resUNI resTHI];
          %  writematrix(results,"C:\Users\power\MRI Project\results.xlsx", 'WriteMode', 'append');
        end

        % Button pushed function: FilterSettingsButton
        function FilterSettingsButtonPushed(app, event)
     if isempty(app.scan_data) || isempty(app.currentscan)
        return
    end

    openFilterSubGui(app);
        end

        % Value changed function: FFTDropDown_3D
        function FFTDropDown_3DValueChanged(app, event)
            updateimages(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.axis_kspace = uiaxes(app.UIFigure);
            app.axis_kspace.XTick = [];
            app.axis_kspace.YTick = [];
            app.axis_kspace.Tag = 'axis_kspace';
            app.axis_image = uiaxes(app.UIFigure);
            app.axis_image.XTick = [];
            app.axis_image.YTick = [];
            app.axis_image.ButtonDownFcn = createCallbackFcn(app, @axis_imageButtonDown, true);
            app.axis_image.Tag = 'axis_image';
            app.axis_coordinates = uiaxes(app.UIFigure);
            app.axis_coordinates.XTick = [];
            app.axis_coordinates.YTick = [];
            app.axis_coordinates.Tag = 'axis_image';
            app.panel_recon = uipanel(app.UIFigure);
            app.panel_recon.Title = 'Reconstruction Options';
            app.panel_recon.Tag = 'panel_recon';
            app.Sizeof2DFFTLabel = uilabel(app.panel_recon);
            app.Sizeof2DFFTLabel.Enable = 'off';
            app.Sizeof2DFFTLabel.Text = '2D FFT';
            app.FFTDropDown = uidropdown(app.panel_recon);
            app.FFTDropDown.Items = {'64', '128', '256', '512'};
            app.FFTDropDown.ValueChangedFcn = createCallbackFcn(app, @FFTDropDownValueChanged, true);
            app.FFTDropDown.Enable = 'off';
            app.FFTDropDown.Tooltip = {'Size of the FFT. Larger sizes of transform are accomplished by symmetrically zero-padding kspace'};
            app.FFTDropDown.Value = '64';
            app.WindowDropDownLabel = uilabel(app.panel_recon);
            app.WindowDropDownLabel.Enable = 'off';
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.panel_recon);
            app.WindowDropDown.Items = {'None', 'Hann', 'Blackman', 'Hamming', 'Kaiser-Bessel', 'Tukey'};
            app.WindowDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowDropDownValueChanged, true);
            app.WindowDropDown.Enable = 'off';
            app.WindowDropDown.Tooltip = {'kspace windowing function'};
            app.WindowDropDown.Value = 'None';
            app.WindowSizeEditFieldLabel = uilabel(app.panel_recon);
            app.WindowSizeEditFieldLabel.Enable = 'off';
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.panel_recon, 'numeric');
            app.WindowSizeEditField.Limits = [0 10];
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.WindowSizeEditField.Enable = 'off';
            app.WindowSizeEditField.Tooltip = {'Size of the windowing function as a fraction of the base image size'};
            app.WindowSizeEditField.Value = 1;
            app.RebuildImagesButton = uibutton(app.panel_recon, 'push');
            app.RebuildImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RebuildImagesButtonPushed, true);
            app.RebuildImagesButton.Enable = 'off';
            app.RebuildImagesButton.Tooltip = {'Rebuilds images from phase-corrected k-space. Useful when testing new reconstruction methods'};
            app.RebuildImagesButton.Text = 'Rebuild Images';
            app.CombinationDropDownLabel = uilabel(app.panel_recon);
            app.CombinationDropDownLabel.Enable = 'off';
            app.CombinationDropDownLabel.Text = 'Combination';
            app.CombinationDropDown = uidropdown(app.panel_recon);
            app.CombinationDropDown.Items = {'RSSQ', 'Adaptive', 'ACS/Walsh'};
            app.CombinationDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinationDropDownValueChanged, true);
            app.CombinationDropDown.Enable = 'off';
            app.CombinationDropDown.Tooltip = {'Algorithm for combining multiple receivers'};
            app.CombinationDropDown.Value = 'Adaptive';
            app.BiasFieldDropDownLabel = uilabel(app.panel_recon);
            app.BiasFieldDropDownLabel.Enable = 'off';
            app.BiasFieldDropDownLabel.Text = 'Bias Field';
            app.BiasFieldDropDown = uidropdown(app.panel_recon);
            app.BiasFieldDropDown.Items = {'Off', 'ACS'};
            app.BiasFieldDropDown.Enable = 'off';
            app.BiasFieldDropDown.Tooltip = {'Attempt to correct uniformity inhomogeneity due to coil sensitivities'};
            app.BiasFieldDropDown.Value = 'Off';
            app.FilterSettingsButton = uibutton(app.panel_recon, 'push');
            app.FilterSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @FilterSettingsButtonPushed, true);
            app.FilterSettingsButton.Enable = 'off';
            app.FilterSettingsButton.Tooltip = {'Rebuilds images from phase-corrected k-space. Useful when testing new reconstruction methods'};
            app.FilterSettingsButton.Text = 'Denoising';
            app.Sizeof3DFFTLabel = uilabel(app.panel_recon);
            app.Sizeof3DFFTLabel.Enable = 'off';
            app.Sizeof3DFFTLabel.Text = '3D FFT';
            app.FFTDropDown_3D = uidropdown(app.panel_recon);
            app.FFTDropDown_3D.Items = {'64', '128', '256', '512'};
            app.FFTDropDown_3D.ValueChangedFcn = createCallbackFcn(app, @FFTDropDown_3DValueChanged, true);
            app.FFTDropDown_3D.Enable = 'off';
            app.FFTDropDown_3D.Tooltip = {'Size of the FFT. Larger sizes of transform are accomplished by symmetrically zero-padding kspace'};
            app.FFTDropDown_3D.Value = '64';
            app.CurrentImagePanel = uipanel(app.UIFigure);
            app.CurrentImagePanel.Title = 'Current Image';
            app.EvolutionFieldDropDownLabel = uilabel(app.CurrentImagePanel);
            app.EvolutionFieldDropDownLabel.Enable = 'off';
            app.EvolutionFieldDropDownLabel.Text = 'Evolution Field';
            app.EvolutionFieldDropDown = uidropdown(app.CurrentImagePanel);
            app.EvolutionFieldDropDown.Items = {'200'};
            app.EvolutionFieldDropDown.ValueChangedFcn = createCallbackFcn(app, @EvolutionFieldDropDownValueChanged, true);
            app.EvolutionFieldDropDown.Enable = 'off';
            app.EvolutionFieldDropDown.Tooltip = {'Evolution field in mT'};
            app.EvolutionFieldDropDown.Value = '200';
            app.EvolutionTimeDropDownLabel = uilabel(app.CurrentImagePanel);
            app.EvolutionTimeDropDownLabel.Enable = 'off';
            app.EvolutionTimeDropDownLabel.Text = 'Evolution Time';
            app.EvolutionTimeDropDown = uidropdown(app.CurrentImagePanel);
            app.EvolutionTimeDropDown.Items = {'0'};
            app.EvolutionTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @EvolutionTimeDropDownValueChanged, true);
            app.EvolutionTimeDropDown.Enable = 'off';
            app.EvolutionTimeDropDown.Tooltip = {'Evolution time in ms'};
            app.EvolutionTimeDropDown.Value = '0';
            app.SliceDropDownLabel = uilabel(app.CurrentImagePanel);
            app.SliceDropDownLabel.Enable = 'off';
            app.SliceDropDownLabel.Text = 'Slice';
            app.SliceDropDown = uidropdown(app.CurrentImagePanel);
            app.SliceDropDown.Items = {'1'};
            app.SliceDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceDropDownValueChanged, true);
            app.SliceDropDown.Enable = 'off';
            app.SliceDropDown.Tooltip = {'Slice index'};
            app.SliceDropDown.Value = '1';
            app.ImageTypeDropDownLabel = uilabel(app.CurrentImagePanel);
            app.ImageTypeDropDownLabel.Enable = 'off';
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.CurrentImagePanel);
            app.ImageTypeDropDown.Items = {'Magnitude', 'Phase', 'Relaxometry Map'};
            app.ImageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown.Enable = 'off';
            app.ImageTypeDropDown.Tooltip = {'Displayed image can be the magnitude image, a phase map or a T1 or R1 parameter map'};
            app.ImageTypeDropDown.Value = 'Magnitude';
            app.RegisterImagesButton = uibutton(app.CurrentImagePanel, 'push');
            app.RegisterImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterImagesButtonPushed, true);
            app.RegisterImagesButton.Enable = 'off';
            app.RegisterImagesButton.Text = 'Register Images';
            app.DisplayOptionsPanel = uipanel(app.UIFigure);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.WindowLevelSliderLabel = uilabel(app.DisplayOptionsPanel);
            app.WindowLevelSliderLabel.Enable = 'off';
            app.WindowLevelSliderLabel.Text = 'Window Level';
            app.WindowLevelSlider = uislider(app.DisplayOptionsPanel);
            app.WindowLevelSlider.Limits = [-1 1];
            app.WindowLevelSlider.MajorTicks = [];
            app.WindowLevelSlider.MajorTickLabels = {''};
            app.WindowLevelSlider.ValueChangedFcn = createCallbackFcn(app, @WindowLevelSliderValueChanged, true);
            app.WindowLevelSlider.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.WindowLevelSlider.Enable = 'off';
            app.WindowWidthSliderLabel = uilabel(app.DisplayOptionsPanel);
            app.WindowWidthSliderLabel.Enable = 'off';
            app.WindowWidthSliderLabel.Text = 'Window Width';
            app.WindowWidthSlider = uislider(app.DisplayOptionsPanel);
            app.WindowWidthSlider.Limits = [0 1];
            app.WindowWidthSlider.MajorTicks = [];
            app.WindowWidthSlider.MajorTickLabels = {''};
            app.WindowWidthSlider.ValueChangedFcn = createCallbackFcn(app, @WindowWidthSliderValueChanged, true);
            app.WindowWidthSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.WindowWidthSlider.Enable = 'off';
            app.WindowWidthSlider.Value = 1;
            app.RotateImageButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.RotateImageButton.ButtonPushedFcn = createCallbackFcn(app, @RotateImageButtonPushed, true);
            app.RotateImageButton.Enable = 'off';
            app.RotateImageButton.Text = 'Rotate Image';
            app.FlipHorizontallyButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Enable = 'off';
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.FlipVerticallyButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Enable = 'off';
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.IndividualNormalisationButton = uibutton(app.DisplayOptionsPanel, 'state');
            app.IndividualNormalisationButton.ValueChangedFcn = createCallbackFcn(app, @IndividualNormalisationButtonValueChanged, true);
            app.IndividualNormalisationButton.Tooltip = {'Displayed images can either be individually normalised (using imagesc) or else scaled to the maximum intensity of the first image in the data set (usually an image acquired at 200 mt)'};
            app.IndividualNormalisationButton.Enable = 'off';
            app.IndividualNormalisationButton.Text = 'Individual Normalisation';
            app.ScanListPanel = uipanel(app.UIFigure);
            app.ScanListPanel.Title = 'Scan List';
            app.ScanList = uilistbox(app.ScanListPanel);
            app.ScanList.Items = {'Scans will appear here'};
            app.ScanList.ValueChangedFcn = createCallbackFcn(app, @ScanListValueChanged, true);
            app.ScanList.Value = 'Scans will appear here';
            app.RelaxometryPanel = uipanel(app.UIFigure);
            app.RelaxometryPanel.Title = 'Relaxometry';
            app.GenerateRelaxationMapsButton = uibutton(app.RelaxometryPanel, 'push');
            app.GenerateRelaxationMapsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRelaxationMapsButtonPushed, true);
            app.GenerateRelaxationMapsButton.Enable = 'off';
            app.GenerateRelaxationMapsButton.Tooltip = {'Produces voxel-wise relaxation parameter maps. See MapRelaxation for details of how the maps are produced'};
            app.GenerateRelaxationMapsButton.Text = 'Generate Relaxation Maps';
            app.GenerateROIDispersionCurveButton = uibutton(app.RelaxometryPanel, 'push');
            app.GenerateROIDispersionCurveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateROIDispersionCurveButtonPushed, true);
            app.GenerateROIDispersionCurveButton.Enable = 'off';
            app.GenerateROIDispersionCurveButton.Tooltip = {'Produces a ROI based dispersion curve. See T1dispersion for details on how the results are derived.'};
            app.GenerateROIDispersionCurveButton.Text = 'Generate ROI Dispersion Curve';
            app.CheckfitsButton = uibutton(app.RelaxometryPanel, 'state');
            app.CheckfitsButton.Tooltip = {'Displays the relaxation fits when either relaxation maps or ROI dispersion curves are generated'};
            app.CheckfitsButton.Enable = 'off';
            app.CheckfitsButton.Text = 'Check fits';
            app.LogarithmicMapsButton = uibutton(app.RelaxometryPanel, 'state');
            app.LogarithmicMapsButton.ValueChangedFcn = createCallbackFcn(app, @LogarithmicMapsButtonValueChanged, true);
            app.LogarithmicMapsButton.Tooltip = {'Visualises relaxation maps either linearly or in log scale. Log scale can be useful for comparing relaxation between different magnetic fields where there is a large change in T1 values'};
            app.LogarithmicMapsButton.Enable = 'off';
            app.LogarithmicMapsButton.Text = 'Logarithmic Maps';
            app.DisplayingasT1Button = uibutton(app.RelaxometryPanel, 'state');
            app.DisplayingasT1Button.ValueChangedFcn = createCallbackFcn(app, @DisplayingasT1ButtonValueChanged, true);
            app.DisplayingasT1Button.Tooltip = {'Displays dispersion curves or relaxation maps in terms of R1 or T1'};
            app.DisplayingasT1Button.Enable = 'off';
            app.DisplayingasT1Button.Text = 'Displaying as T1';
            app.ColourAxisUpperLimitEditFieldLabel = uilabel(app.RelaxometryPanel);
            app.ColourAxisUpperLimitEditFieldLabel.Enable = 'off';
            app.ColourAxisUpperLimitEditFieldLabel.Text = 'Colour Axis Upper Limit';
            app.ColourAxisUpperLimitEditField = uieditfield(app.RelaxometryPanel, 'numeric');
            app.ColourAxisUpperLimitEditField.Limits = [0 Inf];
            app.ColourAxisUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @ColourAxisUpperLimitEditFieldValueChanged, true);
            app.ColourAxisUpperLimitEditField.Enable = 'off';
            app.ColourAxisUpperLimitEditField.Tooltip = {'Upper limit of displayed T1/R1 values in relaxation maps'};
            app.ColourAxisUpperLimitEditField.Value = 1000;
            app.ColourAxisLowerLimitEditFieldLabel = uilabel(app.RelaxometryPanel);
            app.ColourAxisLowerLimitEditFieldLabel.Enable = 'off';
            app.ColourAxisLowerLimitEditFieldLabel.Text = 'Colour Axis Lower Limit';
            app.ColourAxisLowerLimitEditField = uieditfield(app.RelaxometryPanel, 'numeric');
            app.ColourAxisLowerLimitEditField.Limits = [0 Inf];
            app.ColourAxisLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @ColourAxisLowerLimitEditFieldValueChanged, true);
            app.ColourAxisLowerLimitEditField.Enable = 'off';
            app.ColourAxisLowerLimitEditField.Tooltip = {'Lower limit of displayed T1/R1 values in relaxation maps'};
            app.Panel_2 = uipanel(app.UIFigure);
            app.LoadDataSetButton = uibutton(app.Panel_2, 'push');
            app.LoadDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSetButtonPushed, true);
            app.LoadDataSetButton.Tooltip = {'Open a FFC data set. '};
            app.LoadDataSetButton.Text = 'Load Data Set';
            app.ReprocessingButton = uibutton(app.Panel_2, 'state');
            app.ReprocessingButton.Tooltip = {'Forces the complete reconstruction of the data set. Otherwise the software will look for preprocessed data and open that instead. Can be time consuming for large data sets.'};
            app.ReprocessingButton.Text = 'Reprocessing';
            app.BackgroundselectionButton = uibutton(app.Panel_2, 'state');
            app.BackgroundselectionButton.Tooltip = {'Allows the user to select the image background to aid in image reconstruction. Usually not needed but may be useful if significant ghosting remains.'};
            app.BackgroundselectionButton.Text = 'Background selection';
            app.ExportOptionsPanel = uipanel(app.UIFigure);
            app.ExportOptionsPanel.Title = 'Export Options';
            app.ExportscantoworkspaceButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportscantoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportscantoworkspaceButtonPushed, true);
            app.ExportscantoworkspaceButton.Enable = 'off';
            app.ExportscantoworkspaceButton.Tooltip = {'Export the scan object to the MATLAB workspace'};
            app.ExportscantoworkspaceButton.Text = 'Export scan to workspace';
            app.ExportallimagesButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportallimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallimagesButtonPushed, true);
            app.ExportallimagesButton.Enable = 'off';
            app.ExportallimagesButton.Tooltip = {'Export the current data set (magnitude, phase or relaxation map) as a series of .tif images.'};
            app.ExportallimagesButton.Text = 'Export all images';
            app.ExportstackedimagesButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportstackedimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportstackedimagesButtonPushed, true);
            app.ExportstackedimagesButton.Enable = 'off';
            app.ExportstackedimagesButton.Tooltip = {'Export the current data set (magnitude, phase or relaxation map) as a stacked .tif image. For magnitude and phase images a stacked image of evolution times will be generated for each evolution field. For relaxation maps a single stack containing all relaxation maps will be generated.'};
            app.ExportstackedimagesButton.Text = 'Export stacked images';
            app.ExportscanparameterstoworkspaceButton = uibutton(app.ExportOptionsPanel, 'push');
            app.ExportscanparameterstoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportscanparameterstoworkspaceButtonPushed, true);
            app.ExportscanparameterstoworkspaceButton.Enable = 'off';
            app.ExportscanparameterstoworkspaceButton.Tooltip = {'Write the current scan parameters in the workspace'};
            app.ExportscanparameterstoworkspaceButton.Text = 'Export scan parameters to workspace';
            app.CreateFigurePanel = uipanel(app.ExportOptionsPanel);
            app.CreateFigurePanel.Title = 'Create Figure';
            app.kspaceButton = uibutton(app.CreateFigurePanel, 'push');
            app.kspaceButton.ButtonPushedFcn = createCallbackFcn(app, @kspaceButtonPushed, true);
            app.kspaceButton.Enable = 'off';
            app.kspaceButton.Tooltip = {'Produce a new figure containing the currently displayed k-space matrix.'};
            app.kspaceButton.Text = 'k-space';
            app.ImageButton = uibutton(app.CreateFigurePanel, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Enable = 'off';
            app.ImageButton.Tooltip = {'Produce a new figure containing the currently displayed image.'};
            app.ImageButton.Text = 'Image';
            app.MultichannelDropDown = uilistbox(app.UIFigure);
            app.MultichannelDropDown.Items = {};
            app.MultichannelDropDown.Multiselect = 'on';
            app.MultichannelDropDown.ValueChangedFcn = createCallbackFcn(app, @MultichannelDropDownValueChanged2, true);
            app.MultichannelDropDown.Enable = 'off';
            app.MultichannelDropDown.Tooltip = {'Multichannel reconstruction options. Currently supports sum of squares and channel averaging. Individual channels can also be inspected.'};
            app.MultichannelDropDown.Value = {};
            app.QualityAssurancePanel = uipanel(app.UIFigure);
            app.QualityAssurancePanel.Title = 'Quality Assurance';
            app.GeometricAccButton = uibutton(app.QualityAssurancePanel, 'push');
            app.GeometricAccButton.ButtonPushedFcn = createCallbackFcn(app, @GeometricAccButtonPushed, true);
            app.GeometricAccButton.Text = 'Geometric Acc';
            app.UniformityButton = uibutton(app.QualityAssurancePanel, 'push');
            app.UniformityButton.ButtonPushedFcn = createCallbackFcn(app, @UniformityButtonPushed, true);
            app.UniformityButton.Text = 'Uniformity';
            app.SNRButton = uibutton(app.QualityAssurancePanel, 'push');
            app.SNRButton.ButtonPushedFcn = createCallbackFcn(app, @SNRButtonPushed, true);
            app.SNRButton.Enable = 'off';
            app.SNRButton.Tooltip = {'Attempts to determine the overall image SNR. This method is better suited to QA style measurements with phantoms rather than in-vivo experiments.'};
            app.SNRButton.Text = 'SNR';
            app.THKButton = uibutton(app.QualityAssurancePanel, 'push');
            app.THKButton.ButtonPushedFcn = createCallbackFcn(app, @THKButtonPushed, true);
            app.THKButton.Text = 'THK';
            app.AllButton = uibutton(app.QualityAssurancePanel, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFCProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end